classdef DGZConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProcessDataUIFigure         matlab.ui.Figure
        Label                       matlab.ui.control.Label
        ListBox                     matlab.ui.control.ListBox
        RunTCLCodeButton            matlab.ui.control.Button
        DataSourceFolderPanel       matlab.ui.container.Panel
        ChangeButton                matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        FiltersPanel                matlab.ui.container.Panel
        StartDateEditFieldLabel     matlab.ui.control.Label
        StartDateEditField          matlab.ui.control.EditField
        EndDateEditFieldLabel       matlab.ui.control.Label
        EndDateEditField            matlab.ui.control.EditField
        Filter2EditFieldLabel       matlab.ui.control.Label
        Filter2EditField            matlab.ui.control.EditField
        Filter1EditFieldLabel       matlab.ui.control.Label
        Filter1EditField            matlab.ui.control.EditField
        DataDestinationFolderPanel  matlab.ui.container.Panel
        ChangeButton_2              matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        UpdateDBButton              matlab.ui.control.Button
        ResetDBButton               matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function filterList(app)
            global filesListFull
            %read in filter settings
            f1=app.Filter1EditField.Value;
            f2=app.Filter2EditField.Value;
            d1=app.StartDateEditField.Value;
            d2=app.EndDateEditField.Value;
            
            %construct list of dates to check
            dates=listOfDates(app,d1,d2);
            
            %step through files and see if it meets filter settings
            newFiles={};
            ind=0;
            for i=1:size(filesListFull,2)
                if contains(filesListFull{i},f1) && contains(filesListFull{i},f2) && contains(filesListFull{i},dates)
                    ind=ind+1;
                    name=filesListFull{i};
                    newFiles{ind} = name(1:end-4);
                end
            end
            app.ListBox.Items=newFiles;
        end
        
        function dates = listOfDates(~,d1,d2)
            %error checking
            %has characters other than digits
            if (~isempty(d1) && isnan(str2double(d1))) || (~isempty(d2) && isnan(str2double(d2)))
                errordlg('Enter a date, not gibberish!')
                dates='';
                return;
            end
            %wrong length. can be zero or 6.
            if (~isempty(d1) && length(d1) ~= 6) || (~isempty(d2) && length(d2) ~= 6)
                errordlg('Date is weirdly formatted. MMDDYY please.')
                dates='';
                return;
            end
            
            % determines if we need to make a list of a range of dates
            dates='';
            if isempty(d1) && isempty(d2)       %if we have no dates
                dates='';
            elseif isempty(d1) && ~isempty(d2)  %if we have one date d2
                dates=d2;
            elseif ~isempty(d1) && isempty(d2)  %if we have one date d1
                dates=d1;
            elseif d1 == d2                     %if both dates are the same
                dates=d1;
            else                                %otherwise we have two different dates and need to count days between
                date1=str2num(reshape(d1,[2,3])'); %turns '123456' into [12; 34; 56]
                date2=str2num(reshape(d2,[2,3])');
                
                first=datenum(date1(3)+2000,date1(1),date1(2));    %first day (e.g., 737163)
                last=datenum(date2(3)+2000,date2(1),date2(2));     %last day
                
                days=min([first last]):max([first last]); %in case the dates were entered backwards
                
                %make list of dates in cells
                for i=1:size(days,2)
                    dates{i}=datestr(days(i),'mmddyy');
                end
                
            end
            
        end
        
        function list = findUnenteredFiles(app,conn)
            %%    checks for dgzs in output directory that have not been entered in the database and returns that list
            % make sure this database has the dataFilesTable
            createTrialsTable = 'CREATE TABLE IF NOT EXISTS "dataFilesTable" ( "DataFile" TEXT NOT NULL UNIQUE ON CONFLICT REPLACE, "Entered" NUMERIC NOT NULL)';
            mksqlite(conn,createTrialsTable); %takes over 1 second with 10s of thousands of tables
            mksqlite( 'result_type', 0);  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            filesInDB=mksqlite(conn,'SELECT DataFile FROM dataFilesTable'); %get list of file names entered into database
            filesInDB={filesInDB.DataFile};
            
            % get list of file names in output directory
            fileInfo = dir([app.Label_3.Text '\*.dgz']);
            filesInFolder={fileInfo.name};
            
            %find DGZ files in folder that aren't in this table of DB
            newFiles=setdiff(filesInFolder,filesInDB)'; %if it throws an error here, probably because something else
            
            %add those DGZs to the table, then grab all unentered datafiles
            mksqlite('param_wrapping', 1); %connection id is superfluous
            mksqlite(conn,'begin');
            if ~isempty(newFiles) %if there are new files to insert into database
                mksqlite('INSERT INTO dataFilesTable (DataFile, Entered) VALUES (?,?)', [newFiles num2cell(zeros(size(newFiles)))]')
            end
            list=mksqlite(conn,'SELECT DataFile FROM dataFilesTable WHERE Entered = 0'); %get list of file names entered into database
            mksqlite(conn,'commit');
        end
        
        function pushToDB(app,file,connMain,connGrasp,connEyes,connEphys)
            disp(['Entering ' file ' into DB']); updateCommandWindow(app); drawnow;
            
            %read dgz
            d=readDGZ(app,fullfile(app.Label_3.Text,file));
            
            %modify dgz, do additional calculations to store in db which will be convenient
            d=modifyDGZ(app,d);
            
            %make sure trialsTable exist
            createTrialsTable = 'CREATE TABLE IF NOT EXISTS "trialsTable" ( "UniqueTrial" INTEGER NOT NULL PRIMARY KEY AUTOINCREMENT UNIQUE, "Subject" TEXT, "Date" INTEGER, "Block" INTEGER, "Variant" TEXT, "Trial" INTEGER, "FixOn" INTEGER, "FixOff" INTEGER, "StimOn" INTEGER, "TargetOn" INTEGER, "StimOff" INTEGER, "Answer" INTEGER, "Response" INTEGER, "ResponseTime" INTEGER, "ReactionTime" INTEGER, "Target" INTEGER, "Delay" INTEGER, "HLPads" TEXT, "TouchMode" TEXT, "Congruent" INTEGER, "SampleMode" TEXT, "SampleID" INTEGER, "SampleAvailOn" INTEGER, "SampleAvailOff" INTEGER, "SamplePosition" INTEGER, "SampleScale" INTEGER, "SampleRotation" INTEGER, "Flip" INTEGER,"PostTouchDelay" INTEGER,"Choice1Mode" TEXT, "Choice1ID" INTEGER, "Choice1AvailOn" INTEGER, "Choice1AvailOff" INTEGER, "Choice1Position" INTEGER, "Choice1Scale" INTEGER, "Choice1Rotation" INTEGER, "Choice2Mode" TEXT, "Choice2ID" INTEGER, "Choice2AvailOn" INTEGER, "Choice2AvailOff" INTEGER, "Choice2Position" INTEGER, "Choice2Scale" INTEGER, "Choice2Rotation" INTEGER )';
            mksqlite(connMain,createTrialsTable)
            
            %send to DB
            sendDGZ(app,d,connMain,connGrasp,connEyes,connEphys)
            
            %update table to show uploaded
            mksqlite(connMain,'UPDATE dataFilesTable SET Entered=1 WHERE DataFile = ?', file);
        end


        function d = readDGZ(~,file)
            data = dg_read(file);
            
            a=strsplit(file,'\');
            b=strsplit(a{end},'_');
            subject=b{1};
            
            %ignored columns: load_params, filename, version, status, endtrial, endobs, stimtriggers, onlinesacs, onlineacqs
            % onlineacqs_params, stimevents, stimdata, presrank, ems2, ems_kern,
            %basic info
            d.blocks=data.file;
            for i=1:length(d.blocks)
                d.subject{i,1}=subject;
                d.date(i,1)=str2double([num2str(data.month(i,:),'%02.f') num2str(data.day(i,:),'%02.f') num2str(data.year(i,:),'%02.f')]);
            end
            
            
            d.stim=data.stimtype;
            d.variant=data.variant;
            
            d.fixOn=data.fixon;
            d.fixOff=data.fixoff;
            d.stimOn=data.stimon;
            d.stimOff=data.stimoff;
            d.response=data.resp;
            d.responseTime=data.response;
            d.answer=data.side;
            d.reactionTime=data.rts;
            d.target=data.target;
            d.delay=data.delay;
            d.touchMode=data.touch_mode;
            d.trials=data.obsid;
            
            %sample info
            d.sampleMode=data.sample_mode;
            d.sampleID=data.sample_id;
            d.sampleSide=data.sample_side;
            d.sampleAvailOn=data.sample_availOn;
            d.sampleAvailOff=data.sample_availOff;
            d.samplePosition=data.sample_position;
            d.sampleScale=data.sample_scale;
            d.sampleRotation=data.sample_rotation;
            d.sampleCoords=data.grasp_coords_sample;
            try
                d.flip=data.flip;
                d.post_touch_delay=data.post_touch_delay;
            catch
                d.flip = zeros(size(d.blocks));
                d.post_touch_delay = ones(size(d.blocks)) .* -1;
            end
            try
                d.targetOn = data.targon;
            catch
                d.targetOn = d.stimOn;
            end
            
            try
                d.hlPads = data.hl_pads;
            catch
                d.hlPads = repmat({0},size(d.blocks));
            end
            
            %choice 1 (left touch) info
            d.choice1Mode=data.choice1_mode;
            d.choice1ID=data.choice1_id;
            d.choice1Port=data.choice1_port;
            d.choice1Angle=data.choice1_rotation;
            d.choice1AvailOn=data.choice1_availOn;
            d.choice1AvailOff=data.choice1_availOff;
            d.choice1Match=data.choice1_ismatch;
            d.choice1Coords=data.grasp_coords_left;
            d.choice1Rotation=data.choice1_rotation;
            
            %choice 2 (right touch) info
            d.choice2Mode=data.choice2_mode;
            d.choice2ID=data.choice2_id;
            d.choice2Port=data.choice2_port;
            d.choice2Angle=data.choice2_rotation;
            d.choice2AvailOn=data.choice2_availOn;
            d.choice2AvailOff=data.choice2_availOff;
            d.choice2Match=data.choice2_ismatch;
            d.choice2Coords=data.grasp_coords_right;
            d.choice2Rotation=data.choice2_rotation;
            
            %psychophys data
            d.ems=data.ems;
            try d.ems2=data.ems2; catch; d.ems2={}; end
            d.sacStart=data.sactimes;
            d.sacStop=data.sacstops;
            d.blinkStart=data.blinkstarts;
            d.blinkStop=data.blinkstops;
            d.graspTimes=data.grasp_pcts_times;
            d.graspLeftTimes=data.grasp_pcts_left_times;
            d.graspRightTimes=data.grasp_pcts_right_times;
            d.graspPctsLeft=data.grasp_pcts_left;
            d.graspPctsRight=data.grasp_pcts_right;
            d.graspValsLeft=data.grasp_vals_left;
            d.graspValsRight=data.grasp_vals_right;
            
            
        end
        
        function d = modifyDGZ(~,d)
            %%% intended for any additional computations you want to make before uploading to database
            %% Decide if this is a block of congruent trials
            blocks=unique([d.date d.blocks],'rows'); % unique day & block
            
            for i=1:size(blocks,1)
                rows=find(d.date==blocks(i,1) & d.blocks==blocks(i,2));
                angles=[];
                for ii=rows'
                    if strcmp(d.touchMode(ii,:),'left') %If its a left block, grab sample and choice1 info
                        angles=[angles; d.sampleRotation(ii) d.choice1Rotation(ii)];
                    else
                        angles=[angles; d.sampleRotation(ii) d.choice2Rotation(ii)];
                    end
                end
                
                if (sum(angles(:,1)==angles(:,2))) == size(angles,1) %If this is a congruent block
                    d.congruent(rows)=ones(length(rows),1);
                else
                    d.congruent(rows)=zeros(length(rows),1);
                end
            end
            d.congruent=d.congruent';
            
        end
        
        function sendDGZ(~,d,connMain,connGrasp,connEyes,connEphys)
            %initialize the param_wrapping so that everything gets sent at once, cuts time by around 80%
            mksqlite('param_wrapping', 1); %connection id is superfluous
            mksqlite(connMain,'begin');
            mksqlite(connGrasp,'begin');
            mksqlite(connEyes,'begin');
            
            for i=1:length(d.trials) %For each trial
                clear gvl gpl gvr gpr
                
                %These values assume the sample is on the screen, choice 1 is left object, choice 2 is right object
                sPos=[num2str(d.samplePosition{i,1}(1,1)) ',' num2str(d.samplePosition{i,1}(2,1)) ',' num2str(d.samplePosition{i,1}(3,1))];
                sScale = d.sampleScale(i);
                sRot = d.sampleRotation(i);
                
                c1Pos=0;
                c1Scale = 0;
                c1Rot = d.choice1Rotation(i);
                
                c2Pos=1;
                c2Scale = 0;
                c2Rot = d.choice2Rotation(i);
                
                touchMode=deblank(d.touchMode(i,:));
                
                %Check to make sure this trial isn't already in the database.
                trial=mksqlite(connMain,['SELECT UniqueTrial FROM trialsTable WHERE Date = "' num2str(d.date(i)) '" AND Block = "' num2str(d.blocks(i)) '" AND Trial = "' num2str(d.trials(i)) '"']);
                if ~isempty(trial)
                    disp skipped
                    continue;
                end
                
                %assembles trial information for trials table and grasp table. splits out because values that need to be stored depend on whether there was a right and left object to touch
                if strcmp(touchMode,'left')
                    %Set trial information
                    trialcolnames = 'Subject,Date,Block,Variant,Trial,FixOn,FixOff,StimOn,TargetOn,StimOff,Answer,Response,ResponseTime,ReactionTime,Target,Delay,HLPads,TouchMode,Congruent,SampleMode,SampleID,SampleAvailOn,SampleAvailOff,SamplePosition,SampleScale,SampleRotation,Flip,PostTouchDelay,Choice1Mode,Choice1ID,Choice1AvailOn,Choice1AvailOff,Choice1Position,Choice1Scale,Choice1Rotation';
                    trialValues = {d.subject{i,:}, d.date(i), d.blocks(i), d.variant(i,:), d.trials(i), d.fixOn(i), d.fixOff(i), d.stimOn(i), d.targetOn(i), d.stimOff(i), d.answer(i), d.response(i), d.responseTime(i), d.reactionTime(i), d.target(i), d.delay(i), num2str(d.hlPads{1,:}'), touchMode, d.congruent(i), ...
                        deblank(d.sampleMode(i,:)),d.sampleID(i),d.sampleAvailOn(i),d.sampleAvailOff(i),sPos,sScale,sRot, d.flip(i), d.post_touch_delay(i), ...
                        deblank(d.choice1Mode(i,:)),d.choice1ID(i),d.choice1AvailOn(i),d.choice1AvailOff(i),c1Pos,c1Scale,c1Rot};


                    %Calculate pretrial, trial, posttrial grasp times
                    rt=d.responseTime(i);
                    times=d.graspLeftTimes{i};
                    diffs=diff(times);
                    tp=ones(size(times));
                    tp(times>(rt-100))=2;
                    tp(1:find(diffs<0))=0;
                    
                    %Set grasp table vals
                    gvlCell=d.graspValsLeft{i,1};
                    gplCell=d.graspPctsLeft{i,1};
                    for ii=1:size(gvrCell,1)
                        gvl(ii,1:12)=gvlCell{ii,1}';
                        gpl(ii,1:12)=gplCell{ii,1}';
                    end
                    
                    if ~exist('gvl')
                        i
                        disp 'missing grasp data';
                        continue;
                    end
                    
                    %add to trials table
                    qstring=repmat('?,',1,size(trialValues,2));
                    mksqlite(connMain,['INSERT INTO trialsTable (' trialcolnames ') values (' qstring(1:end-1) ')'],trialValues)
                    
                    
                    graspValues=num2cell([tp d.graspTimes{i} d.graspLeftTimes{i} gvl gpl]');
                    
                    newRow=num2str(cell2mat(struct2cell(mksqlite(connMain,'SELECT last_insert_rowid()'))));
                    createGraspTable = ['create table graspTable' newRow ' (TrialPeriod INTEGER, stimTime NUMERIC, Time_1 NUMERIC, Time_2 NUMERIC, rawPad1_1 NUMERIC, rawPad1_2 NUMERIC, rawPad1_3 NUMERIC, rawPad1_4 NUMERIC, rawPad1_5 NUMERIC, rawPad1_6 NUMERIC, rawPad1_7 NUMERIC, rawPad1_8 NUMERIC, rawPad1_9 NUMERIC, rawPad1_10 NUMERIC, rawPad1_11 NUMERIC, rawPad1_12 NUMERIC, pctPad1_1 NUMERIC, pctPad1_2 NUMERIC, pctPad1_3 NUMERIC, pctPad1_4 NUMERIC, pctPad1_5 NUMERIC, pctPad1_6 NUMERIC, pctPad1_7 NUMERIC, pctPad1_8 NUMERIC, pctPad1_9 NUMERIC, pctPad1_10 NUMERIC, pctPad1_11 NUMERIC, pctPad1_12 NUMERIC)'];
                elseif strcmp(touchMode,'right')
                    %Set trial information
                    trialcolnames = 'Subject,Date,Block,Variant,Trial,FixOn,FixOff,StimOn,TargetOn,StimOff,Answer,Response,ResponseTime,ReactionTime,Target,Delay,HLPads,TouchMode,Congruent,SampleMode,SampleID,SampleAvailOn,SampleAvailOff,SamplePosition,SampleScale,SampleRotation,Flip,PostTouchDelay,Choice2Mode,Choice2ID,Choice2AvailOn,Choice2AvailOff,Choice2Position,Choice2Scale,Choice2Rotation';
                    trialValues = {d.subject{i,:}, d.date(i), d.blocks(i), d.variant(i,:), d.trials(i), d.fixOn(i), d.fixOff(i), d.stimOn(i), d.targetOn(i), d.stimOff(i), d.answer(i), d.response(i), d.responseTime(i), d.reactionTime(i), d.target(i), d.delay(i), num2str(d.hlPads{1,:}'), touchMode, d.congruent(i), ...
                        deblank(d.sampleMode(i,:)),d.sampleID(i),d.sampleAvailOn(i),d.sampleAvailOff(i),sPos,sScale,sRot, d.flip(i), d.post_touch_delay(i), ...
                        deblank(d.choice2Mode(i,:)),d.choice2ID(i),d.choice2AvailOn(i),d.choice2AvailOff(i),c2Pos,c2Scale,c2Rot};


                    %Calculate pretrial, trial, posttrial grasp times
                    rt=d.responseTime(i);
                    times=d.graspRightTimes{i};
                    diffs=diff(times);
                    tp=ones(size(times));
                    tp(times>(rt-100))=2;
                    tp(1:find(diffs<0))=0;
                    
                    %Set grasp table vals
                    gvrCell=d.graspValsRight{i,1};
                    gprCell=d.graspPctsRight{i,1};
                    for ii=1:size(gvrCell,1)
                        gvr(ii,1:12)=gvrCell{ii,1}';
                        gpr(ii,1:12)=gprCell{ii,1}';
                    end
                    
                    if ~exist('gvl')
                        i
                        disp 'missing grasp data';
                        continue;
                    end
                    
                    %add to trials table
                    qstring=repmat('?,',1,size(trialValues,2));
                    mksqlite(connMain,['INSERT INTO trialsTable (' trialcolnames ') values (' qstring(1:end-1) ')'],trialValues)
                    
                    
                    graspValues=num2cell([tp d.graspTimes{i} d.graspRightTimes{i} gvr gpr]');
                    
                    newRow=num2str(cell2mat(struct2cell(mksqlite(connMain,'SELECT last_insert_rowid()'))));
                    createGraspTable = ['create table graspTable' newRow ' (TrialPeriod INTEGER, stimTime NUMERIC, Time_2 NUMERIC, rawPad2_1 NUMERIC, rawPad2_2 NUMERIC, rawPad2_3 NUMERIC, rawPad2_4 NUMERIC, rawPad2_5 NUMERIC, rawPad2_6 NUMERIC, rawPad2_7 NUMERIC, rawPad2_8 NUMERIC, rawPad2_9 NUMERIC, rawPad2_10 NUMERIC, rawPad2_11 NUMERIC, rawPad2_12 NUMERIC, pctPad2_1 NUMERIC, pctPad2_2 NUMERIC, pctPad2_3 NUMERIC, pctPad2_4 NUMERIC, pctPad2_5 NUMERIC, pctPad2_6 NUMERIC, pctPad2_7 NUMERIC, pctPad2_8 NUMERIC, pctPad2_9 NUMERIC, pctPad2_10 NUMERIC, pctPad2_11 NUMERIC, pctPad2_12 NUMERIC)'];
                    
                else
                    %Set trial information
                    trialcolnames = 'Subject,Date,Block,Variant,Trial,FixOn,FixOff,StimOn,TargetOn,StimOff,Answer,Response,ResponseTime,ReactionTime,Target,Delay,HLPads,TouchMode,Congruent,SampleMode,SampleID,SampleAvailOn,SampleAvailOff,SamplePosition,SampleScale,SampleRotation,Flip,PostTouchDelay,Choice1Mode,Choice1ID,Choice1AvailOn,Choice1AvailOff,Choice1Position,Choice1Scale,Choice1Rotation,Choice2Mode,Choice2ID,Choice2AvailOn,Choice2AvailOff,Choice2Position,Choice2Scale,Choice2Rotation';
                    trialValues = {d.subject{i,:}, d.date(i), d.blocks(i), d.variant(i,:), d.trials(i), d.fixOn(i), d.fixOff(i), d.stimOn(i), d.targetOn(i), d.stimOff(i), d.answer(i), d.response(i), d.responseTime(i), d.reactionTime(i), d.target(i), d.delay(i), num2str(d.hlPads{1,:}'), touchMode, d.congruent(i), ...
                        deblank(d.sampleMode(i,:)),d.sampleID(i),d.sampleAvailOn(i),d.sampleAvailOff(i),sPos,sScale,sRot, d.flip(i), d.post_touch_delay(i), ...
                        deblank(d.choice1Mode(i,:)),d.choice1ID(i),d.choice1AvailOn(i),d.choice1AvailOff(i),c1Pos,c1Scale,c1Rot, ...
                        deblank(d.choice2Mode(i,:)),d.choice2ID(i),d.choice2AvailOn(i),d.choice2AvailOff(i),c2Pos,c2Scale,c2Rot};


                    %Calculate pretrial, trial, posttrial grasp times
                    rt=d.responseTime(i);
                    times=d.graspLeftTimes{i};
                    diffs=diff(times);
                    tp=ones(size(times));
                    tp(times>(rt-100))=2;
                    tp(1:find(diffs<0))=0;
                    
                    %Set grasp table vals
                    gvlCell=d.graspValsLeft{i,1};
                    gplCell=d.graspPctsLeft{i,1};
                    gvrCell=d.graspValsRight{i,1};
                    gprCell=d.graspPctsRight{i,1};
                    for ii=1:size(gvrCell,1)
                        gvl(ii,1:12)=gvlCell{ii,1}';
                        gpl(ii,1:12)=gplCell{ii,1}';
                        gvr(ii,1:12)=gvrCell{ii,1}';
                        gpr(ii,1:12)=gprCell{ii,1}';
                    end
                    
                    
                    if ~exist('gvl')
                        i
                        disp 'missing grasp data';
                        continue;
                    end
                        
                    %add to trials table
                    qstring=repmat('?,',1,size(trialValues,2));
                    mksqlite(connMain,['INSERT INTO trialsTable (' trialcolnames ') values (' qstring(1:end-1) ')'],trialValues)
                        
                    graspValues=num2cell([tp d.graspTimes{i} d.graspLeftTimes{i} d.graspRightTimes{i} gvl gpl gvr gpr]');
                    
                    newRow=num2str(cell2mat(struct2cell(mksqlite(connMain,'SELECT last_insert_rowid()'))));
                    createGraspTable = ['create table graspTable' newRow ' (TrialPeriod INTEGER, stimTime NUMERIC, Time_1 NUMERIC, Time_2 NUMERIC, rawPad1_1 NUMERIC, rawPad1_2 NUMERIC, rawPad1_3 NUMERIC, rawPad1_4 NUMERIC, rawPad1_5 NUMERIC, rawPad1_6 NUMERIC, rawPad1_7 NUMERIC, rawPad1_8 NUMERIC, rawPad1_9 NUMERIC, rawPad1_10 NUMERIC, rawPad1_11 NUMERIC, rawPad1_12 NUMERIC, pctPad1_1 NUMERIC, pctPad1_2 NUMERIC, pctPad1_3 NUMERIC, pctPad1_4 NUMERIC, pctPad1_5 NUMERIC, pctPad1_6 NUMERIC, pctPad1_7 NUMERIC, pctPad1_8 NUMERIC, pctPad1_9 NUMERIC, pctPad1_10 NUMERIC, pctPad1_11 NUMERIC, pctPad1_12 NUMERIC, rawPad2_1 NUMERIC, rawPad2_2 NUMERIC, rawPad2_3 NUMERIC, rawPad2_4 NUMERIC, rawPad2_5 NUMERIC, rawPad2_6 NUMERIC, rawPad2_7 NUMERIC, rawPad2_8 NUMERIC, rawPad2_9 NUMERIC, rawPad2_10 NUMERIC, rawPad2_11 NUMERIC, rawPad2_12 NUMERIC, pctPad2_1 NUMERIC, pctPad2_2 NUMERIC, pctPad2_3 NUMERIC, pctPad2_4 NUMERIC, pctPad2_5 NUMERIC, pctPad2_6 NUMERIC, pctPad2_7 NUMERIC, pctPad2_8 NUMERIC, pctPad2_9 NUMERIC, pctPad2_10 NUMERIC, pctPad2_11 NUMERIC, pctPad2_12 NUMERIC)'];
                end
                
                %eye table
                xs=d.ems{i,1}{2,1};
                ys=d.ems{i,1}{3,1};
                try ps=d.ems2{i,1}{2,1}; catch; ps=ys; end
                %Make logical array showing saccade times
                sacs=zeros(length(xs),1);
                slots=round([d.sacStart{i} d.sacStop{i}]./d.ems{i,1}{1,1}); %Times where saccades occured
                for ii=1:size(slots,1)
                    sacs(slots(ii,1):slots(ii,2))=1;
                end
                %Make logical array showing blink times
                blinks=zeros(length(xs),1);
                slots=round([d.blinkStart{i} d.blinkStop{i}]./d.ems{i,1}{1,1}); %Times where blinks occured
                slots(slots>size(blinks,1))=size(blinks,1);
                slots(slots<1)=1;
                for ii=1:size(slots,1)
                    blinks(slots(ii,1):slots(ii,2))=1;
                end
                
                %fill grasp table
                mksqlite(connGrasp,createGraspTable)
                graspqstring=repmat('?,',1,size(graspValues,1));
                mksqlite(connGrasp,['INSERT INTO graspTable' newRow ' values (' graspqstring(1:end-1) ')'],graspValues)
                
                %fill eye table
                mksqlite(connEyes,['create table eyeTable' newRow ' (X NUMERIC, Y NUMERIC, Saccade NUMERIC, Pupil NUMERIC, Blink NUMERIC, Rate NUMERIC)'])
                mksqlite(connEyes,['INSERT INTO eyeTable' newRow ' (Rate) values (?)'],{1000/d.ems{i,1}{1,1}})
                mksqlite(connEyes,['INSERT INTO eyeTable' newRow ' (X, Y, Pupil, Saccade, Blink) values (?,?,?,?,?)'],num2cell([xs ys ps sacs blinks]'))
            end %end for each trial
            
            mksqlite(connMain,'commit');
            mksqlite(connGrasp,'commit');
            mksqlite(connEyes,'commit');
        end     %end function sendDGZ
        
        function updateCommandWindow(app)
            jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            jCmdWin = jDesktop.getClient('Command Window');
            jTextArea = jCmdWin.getComponent(0).getViewport.getView;
            lines=strsplit(char(jTextArea.getText),'\n');
            first=max(length(lines)-60,1);
            app.TextArea_2.Value=lines(first:length(lines));
        end
        
        function conn = connectToDB(app, file)
            evalc('conn =           mksqlite(0,''open'', file )'); %using evalc suppresses annoying messages from being displayed on connection
            %synch + journal + cache brings from 1732 s to 684 s time needed to add 04/18/18 to 06/05/18 to db!
            mksqlite(conn,'Pragma synchronous = OFF'); % set to synchonous mode. seems to save about 5%.
            mksqlite(conn,'Pragma journal_mode = MEMORY'); % set to store journal in RAM. negligible difference but should be slightly faster
            mksqlite(conn,'Pragma cache_size = 500000'); % use a large cache. saves about 30%
        end
        
    end         %end method
    

    methods (Access = private)

        % Code that executes after component creation
        function startDateStartFcn(app)
            clc
            global filesListFull
            app.Filter1EditField.Value='haptic';
            %set all the default text fields
            app.StartDateEditField.Value = datestr(now,'mmddyy');
            app.EndDateEditField.Value = datestr(now,'mmddyy');
            defaultFolder='L:\projects\encounter\data\abraham';
            app.Label_2.Text = defaultFolder;
            app.Label_3.Text = 'C:\Users\Ryan\Documents\Data\DGZs\Monkey';
            fileList = dir([defaultFolder '\*.dgz']);
            %get the list of files in default folder and go to filterList
            filesListFull={fileList.name};
            filterList(app)
        end

        % Button pushed function: ChangeButton
        function ChangeButtonCallback(app, event)
            global filesListFull
            folder=uigetdir('L:\projects\encounter\data','Choose folder containing DGZs');
            if ~folder; return; end             %if user cancelled, dont continue
            app.Label_2.Text = folder;          %update label
            fileList = dir([folder '\*.dgz']);  %get list of dgzs in this directory
            filesListFull={fileList.name};      %only need the names
            filterList(app)                     %update list of files
        end

        % Value changed function: Filter2EditField
        function Filter2EditFieldValueChanged(app, event)
            filterList(app);
        end

        % Value changed function: Filter1EditField
        function Filter1EditFieldValueChanged(app, event)
            filterList(app);
        end

        % Value changed function: StartDateEditField
        function StartDateEditFieldValueChanged(app, event)
            filterList(app);
        end

        % Value changed function: EndDateEditField
        function EndDateEditFieldValueChanged(app, event)
            filterList(app);
        end

        % Button pushed function: ChangeButton_2
        function ChangeButton_2Pushed(app, event)
            folder=uigetdir('C:\Users\Ryan\Documents\Data\DGZs\Monkey','Choose folder to put DGZs');
            if ~folder; return; end %if user cancelled, dont continue
            app.Label_3.Text = folder;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            %file selections
            value = app.ListBox.Value;
            
            %Get animal name from folder
            a=strsplit(app.Label_2.Text,'\');
            animal=a{end};
            
            %Get output folder and switch slash direction
            outFolder=app.Label_3.Text;
            outFolder(strfind(outFolder,'\'))='/';
            
            %Get options to use in file name
            fil=[app.Filter1EditField.Value app.Filter2EditField.Value]; %append filters for filename
            
            %Find dates in selection
            for i=1:size(value,2)
                sections=strsplit(value{i},'_');    %split the name by underscore
                digits=sections{end};               %grab last section
                dates{i}=digits(1:end-3);           %grab only date not block number
            end
            uds=unique(dates);
            
            %construct output string, separating files by day
            outString='';
            for i=1:size(uds,2) %for each unique day
                outString=[outString ' \ndg_write [load_data']; %get this days' string started
                for ii=1:size(dates,2)              %for each day in input files
                    if contains(value{ii},uds{i})   %if this data file is on the current day, add it
                        outString=[outString ' ' value{ii}];
                    end
                end
                %folder/animal_settings_date
                outString = [outString '] ' outFolder '/' animal '_' fil '_' uds{i} '.dgz'];
            end
            
            %write full tcl code to textbox
            app.TextArea.Value = outString;
        end

        % Button pushed function: RunTCLCodeButton
        function RunTCLCodeButtonPushed(app, event)
            %If there's nothing in the code box, skip
            if isempty(cell2mat(app.TextArea.Value))
                disp('No code to run!'); updateCommandWindow(app); drawnow
                return
            end
            
            %figure out file name and folder to store creation scripts, make it if necessary
            tclFolder=[app.Label_3.Text '\Creation_scripts\'];
            if ~exist(tclFolder); mkdir(tclFolder);  end
            
            %figure out highest numbered file currently in this folder
            files=dir([tclFolder '*.tcl']);
            maxfile=0;
            for i=1:size(files,1)
                full=files(i).name;
                number=str2double(full(1:4));
                if number>maxfile
                    maxfile=number;
                end
            end
            fnum=num2str(maxfile+1,'%04.f'); %up to 3 leading zeros
            
            %save to tcl file
            fname=[tclFolder fnum '.tcl'];  %tcl file which will hold code
            fileID = fopen(fname,'w');
            fprintf(fileID, ['package require dlsh \npackage require loaddata \n' cell2mat(app.TextArea.Value)]);
            fclose(fileID);
            
            disp('Running tcl code...'); updateCommandWindow(app); drawnow;
            
            %execute tcl code
            unix(['c:/usr/local/bin64/tclsh86.exe ' fname]);
            
            %update the "command window"
            updateCommandWindow(app);
        end

        % Button pushed function: UpdateDBButton
        function UpdateDBButtonPushed(app, event)
            tic
            
            %Establish connection to correct database
            a=strsplit(app.Label_3.Text,'\');
            animal=a{end};          %should be either monkey or human

            %connect to DBs and choose pragma settings that speed things up quite a bit
            connMain    = connectToDB(app,['C:\Users\Ryan\Documents\Data\grasp' animal 'Main.db']);
            connGrasp   = connectToDB(app,['C:\Users\Ryan\Documents\Data\grasp' animal 'Grasp.db']);
            connEyes    = connectToDB(app,['C:\Users\Ryan\Documents\Data\grasp' animal 'Eyes.db']);
            connEphys   = connectToDB(app,['C:\Users\Ryan\Documents\Data\grasp' animal 'Ephys.db']);
            
            %figure out which dgz files need to be entered into database
            list = findUnenteredFiles(app,connMain);
            
            %enter them, one at a time
            for i=1:size(list,1)
                pushToDB(app,list(i).DataFile,connMain,connGrasp,connEyes,connEphys);  %reads the DGZ, performs simple calculations, puts it all in DB
            end
            
            %close database and tell user we're done
            mksqlite(connMain, 'close')
            mksqlite(connGrasp, 'close')
            mksqlite(connEyes, 'close')
            mksqlite(connEphys, 'close')
            disp(['Done in ' num2str(toc,'%.2f') ' seconds.']); updateCommandWindow(app); drawnow;
        end

        % Button pushed function: ResetDBButton
        function ResetDBButtonPushed(app, event)
            %put up warning message to get confirmation before proceeding
            answer = questdlg('Are you sure you want to rename current DB and create new empty DB?', 'Sure about that?','Yes','No','No');
            
            %If they don't want to continue, return
            if strcmp(answer,'No'); return; end
            
            disp('Resetting...'); updateCommandWindow(app); drawnow;
            
            %Find current database name
            a=strsplit(app.Label_3.Text,'\');
            animal=a{end};          %should be either monkey or human
            dbNameMain=['C:\Users\Ryan\Documents\Data\grasp' animal 'Main.db'];
            dbNameGrasp=['C:\Users\Ryan\Documents\Data\grasp' animal 'Grasp.db'];
            dbNameEyes=['C:\Users\Ryan\Documents\Data\grasp' animal 'Eyes.db'];
            dbNameEphys=['C:\Users\Ryan\Documents\Data\grasp' animal 'Ephys.db'];
            
            % if it exists, try and change the name
            if exist(dbNameMain,'file')
                try
                    movefile(dbNameMain,[dbNameMain num2str(randperm(10,1))])
                catch
                    disp('Can''t do it. Make sure it''s not open somewhere.'); updateCommandWindow(app); drawnow;
                    return
                end
                try
                    movefile(dbNameGrasp,[dbNameGrasp num2str(randperm(10,1))])
                catch
                    disp('Can''t do it. Make sure it''s not open somewhere.'); updateCommandWindow(app); drawnow;
                    return
                end
                try
                    movefile(dbNameEyes,[dbNameEyes num2str(randperm(10,1))])
                catch
                    disp('Can''t do it. Make sure it''s not open somewhere.'); updateCommandWindow(app); drawnow;
                    return
                end
                try
                    movefile(dbNameEphys,[dbNameEphys num2str(randperm(10,1))])
                catch
                    disp('Can''t do it. Make sure it''s not open somewhere.'); updateCommandWindow(app); drawnow;
                    return
                end
            end
            
            %create new empty DB
            evalc('conn = mksqlite(0,''open'', dbNameMain)'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
            mksqlite(conn, 'close')
            evalc('conn = mksqlite(0,''open'', dbNameGrasp)'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
            mksqlite(conn, 'close')
            evalc('conn = mksqlite(0,''open'', dbNameEyes)'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
            mksqlite(conn, 'close')
            evalc('conn = mksqlite(0,''open'', dbNameEphys)'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
            mksqlite(conn, 'close')
            
            %update db table to indicate that none of the data files have been entered
            UpdateDBButtonPushed(app, event)
        end

        % Close request function: ProcessDataUIFigure
        function ProcessDataUIFigureCloseRequest(app, event)
            delete(app)
            clear all
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProcessDataUIFigure = uifigure;
            app.ProcessDataUIFigure.Name = 'Process Data';
            app.ProcessDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProcessDataUIFigureCloseRequest, true);
            app.Label = uilabel(app.ProcessDataUIFigure);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.ProcessDataUIFigure);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.RunTCLCodeButton = uibutton(app.ProcessDataUIFigure, 'push');
            app.RunTCLCodeButton.ButtonPushedFcn = createCallbackFcn(app, @RunTCLCodeButtonPushed, true);
            app.RunTCLCodeButton.Text = 'Run TCL Code';
            app.DataSourceFolderPanel = uipanel(app.ProcessDataUIFigure);
            app.DataSourceFolderPanel.Title = 'Data Source Folder';
            app.ChangeButton = uibutton(app.DataSourceFolderPanel, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonCallback, true);
            app.ChangeButton.Text = 'Change';
            app.Label_2 = uilabel(app.DataSourceFolderPanel);
            app.FiltersPanel = uipanel(app.ProcessDataUIFigure);
            app.FiltersPanel.Title = 'Filters';
            app.StartDateEditFieldLabel = uilabel(app.FiltersPanel);
            app.StartDateEditFieldLabel.Text = 'Start Date';
            app.StartDateEditField = uieditfield(app.FiltersPanel, 'text');
            app.StartDateEditField.ValueChangedFcn = createCallbackFcn(app, @StartDateEditFieldValueChanged, true);
            app.EndDateEditFieldLabel = uilabel(app.FiltersPanel);
            app.EndDateEditFieldLabel.Text = 'End Date';
            app.EndDateEditField = uieditfield(app.FiltersPanel, 'text');
            app.EndDateEditField.ValueChangedFcn = createCallbackFcn(app, @EndDateEditFieldValueChanged, true);
            app.Filter2EditFieldLabel = uilabel(app.FiltersPanel);
            app.Filter2EditFieldLabel.Text = 'Filter 2';
            app.Filter2EditField = uieditfield(app.FiltersPanel, 'text');
            app.Filter2EditField.ValueChangedFcn = createCallbackFcn(app, @Filter2EditFieldValueChanged, true);
            app.Filter1EditFieldLabel = uilabel(app.FiltersPanel);
            app.Filter1EditFieldLabel.Text = 'Filter 1';
            app.Filter1EditField = uieditfield(app.FiltersPanel, 'text');
            app.Filter1EditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.DataDestinationFolderPanel = uipanel(app.ProcessDataUIFigure);
            app.DataDestinationFolderPanel.Title = 'Data Destination Folder';
            app.ChangeButton_2 = uibutton(app.DataDestinationFolderPanel, 'push');
            app.ChangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_2Pushed, true);
            app.ChangeButton_2.Text = 'Change';
            app.Label_3 = uilabel(app.DataDestinationFolderPanel);
            app.TextArea = uitextarea(app.ProcessDataUIFigure);
            app.UpdateDBButton = uibutton(app.ProcessDataUIFigure, 'push');
            app.UpdateDBButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDBButtonPushed, true);
            app.UpdateDBButton.Text = 'Update DB';
            app.ResetDBButton = uibutton(app.ProcessDataUIFigure, 'push');
            app.ResetDBButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDBButtonPushed, true);
            app.ResetDBButton.Text = 'Reset DB';
            app.TextArea_2 = uitextarea(app.ProcessDataUIFigure);
            app.TextArea_2.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DGZConverter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProcessDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startDateStartFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProcessDataUIFigure)
        end
    end
end