classdef Mortgage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoanAmountEditFieldLabel       matlab.ui.control.Label
        LoanAmountEditField            matlab.ui.control.NumericEditField
        PrincipalInterestUIAxes        matlab.ui.control.UIAxes
        InterestRateEditFieldLabel     matlab.ui.control.Label
        InterestRateEditField          matlab.ui.control.NumericEditField
        LoanPeriodYearsEditFieldLabel  matlab.ui.control.Label
        LoanPeriodYearsEditField       matlab.ui.control.NumericEditField
        MonthlyPaymentButton           matlab.ui.control.Button
        MonthlyPaymentEditField        matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: MonthlyPaymentButton
        function MonthlyPaymentButtonPushed(app, event)
           
            % Calculate the monthly payment
            amount = app.LoanAmountEditField.Value ;
            rate = app.InterestRateEditField.Value/12/100 ;
            nper = 12*app.LoanPeriodYearsEditField.Value ;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables 
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate ;
                principal(i) = payment - interest(i) ;
                balance(i+1) = balance(i) - principal(i) ;
            end
            
            % Plot the principal and interest
            plot(app.PrincipalInterestUIAxes, (1:nper)', interest, ...
                (1:nper)', principal) ;
            xlim(app.PrincipalInterestUIAxes,[0 nper]) ; 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Mortgage Calculator';
            app.LoanAmountEditFieldLabel = uilabel(app.UIFigure);
            app.LoanAmountEditFieldLabel.Text = 'Loan Amount';
            app.LoanAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoanAmountEditField.Limits = [0 10000000];
            app.LoanAmountEditField.ValueDisplayFormat = '%8.f';
            app.LoanAmountEditField.Value = 300000;
            app.PrincipalInterestUIAxes = uiaxes(app.UIFigure);
            title(app.PrincipalInterestUIAxes, 'Principal and Interest')
            app.InterestRateEditFieldLabel = uilabel(app.UIFigure);
            app.InterestRateEditFieldLabel.Text = 'Interest Rate %';
            app.InterestRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterestRateEditField.Limits = [0.001 100];
            app.InterestRateEditField.Value = 4;
            app.LoanPeriodYearsEditFieldLabel = uilabel(app.UIFigure);
            app.LoanPeriodYearsEditFieldLabel.Text = 'Loan Period (Years)';
            app.LoanPeriodYearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoanPeriodYearsEditField.Limits = [10 40];
            app.LoanPeriodYearsEditField.ValueDisplayFormat = '%.0f';
            app.LoanPeriodYearsEditField.Value = 30;
            app.MonthlyPaymentButton = uibutton(app.UIFigure, 'push');
            app.MonthlyPaymentButton.ButtonPushedFcn = createCallbackFcn(app, @MonthlyPaymentButtonPushed, true);
            app.MonthlyPaymentButton.Text = 'Monthly Payment';
            app.MonthlyPaymentEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonthlyPaymentEditField.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mortgage

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end