classdef graspPerformanceViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShowcorrectinselectedconditionsUIFigure  matlab.ui.Figure
        Analysis1Panel        matlab.ui.container.Panel
        Tree                  matlab.ui.container.Tree
        SpeciesDropDownLabel  matlab.ui.control.Label
        SpeciesDropDown       matlab.ui.control.DropDown
        SubjectDropDownLabel  matlab.ui.control.Label
        SubjectDropDown       matlab.ui.control.DropDown
        VisIDLabel            matlab.ui.control.Label
        visID                 matlab.ui.control.DropDown
        LeftIDLabel           matlab.ui.control.Label
        leftID                matlab.ui.control.DropDown
        RightIDLabel          matlab.ui.control.Label
        rightID               matlab.ui.control.DropDown
        AngleLabel            matlab.ui.control.Label
        leftAngle             matlab.ui.control.DropDown
        AngleLabel_2          matlab.ui.control.Label
        rightAngle            matlab.ui.control.DropDown
        AngleLabel_3          matlab.ui.control.Label
        visAngle              matlab.ui.control.DropDown
        leftGraph             matlab.ui.control.UIAxes
        visGraph              matlab.ui.control.UIAxes
        rightGraph            matlab.ui.control.UIAxes
        PerfOverTime          matlab.ui.control.UIAxes
        UITable               matlab.ui.control.Table
    end

    
    methods (Access = private)
        function setSubjectValues(app,side)
            global DataDB
            % connect to db
            species = app.SpeciesDropDown.Value;
            db=['C:\Users\Ryan\Documents\Data\grasp' species 'Main.db'];
            evalc('DataDB = mksqlite(0,''open'', db)'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            
            %check what subjects are in db and set dropdown
            app.SubjectDropDown.Items=mksqlite(DataDB,'SELECT DISTINCT Subject FROM trialsTable'); %get list of file names entered into database
            
            setAvailableDaysAndBlocks(app);
        end
        
        
        function setAvailableDaysAndBlocks(app)
            global DataDB treeHandles
            
            delete(treeHandles)
            tree='app.Tree';
            
            subject = app.SubjectDropDown.Value;
            
            mksqlite( 'result_type', 1 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            dates=mksqlite(DataDB,['SELECT Date, Block FROM trialsTable WHERE Subject = "' subject '"']);   %get list of file names entered into database
            
            dateBlocks=unique([dates.Date dates.Block],'rows');             %find unique combinations of day and block number
            dates=unique(dateBlocks(:,1));                                  %unique dates
            
            %enter dates/blocks into tree structure
            for i=1:size(dates,1)
                date = simpleToPrettyDate(app,dates(i));
                a(i)=uitreenode(eval(tree),'Text',date);
                blocks=dateBlocks(dateBlocks(:,1)==dates(i),2);
                for ii=1:size(blocks,1)
                    uitreenode(a(i),'Text',num2str(blocks(ii)),'NodeData',date);
                end
            end
            
            %need to keep track of tree handles so they can be deleted when subject changes
            treeHandles=a;
        end
        
        function date = simpleToPrettyDate(~,date)
            date=num2str(date);
            a=datenum(str2double(date(end-3:end)),str2double(date(1:end-6)),str2double(date(end-5:end-4)));
            date=datestr(a,29);
        end
        
        function date = prettyToSimpleDate(~,date)
            %changes from '1984-12-05' to 12051984
            date=str2double(datestr(datenum(date),'mmddyyyy'));
        end
        
        function trialsList = findTrialIDs(app,selectedNodes,DataDB,subject)
            selection=[];
            for i=1:size(selectedNodes,1)
                date=selectedNodes(i).NodeData; %usually date except when its only date than this is empty
                block=selectedNodes(i).Text;    %usually block except when its only date than this is date
                if isempty(date)                %convert date and block into 2 element array with date and block
                    row=[prettyToSimpleDate(app,block) nan];
                else
                    row=[prettyToSimpleDate(app,date) str2double(block)];
                end
                selection=[selection; row];     %add to list
            end
            
            [row,~]=find(isnan(selection));     %finds selections of dates
            
            if ~isempty(row)                    %looks for date selection and, if there are single blocks chosen on this date, nan's out the date
                for i=1:size(row,1)
                    if sum(selection(:,1)==selection(row(i),1)) > 1
                        selection(row(i),1)=nan;
                    end
                end
            end
            
            % selection column 1 is date, column 2 is block
            % where column 1 is nan, that means there are specific blocks chosen for that date
            % where only column 2 is nan, that means that we should grab everything for that date
            
            %find uniquetrials from database
            mksqlite( 'result_type', 1 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            trialsList=[];
            for i=1:size(selection,1)
                if isnan(selection(i,1)) %this means we are skipping this whole date and need to find individual blocks
                    continue;
                end
                
                if isnan(selection(i,2)) %this means we are grabbing everything for this date
                    trials=mksqlite(DataDB,['SELECT UniqueTrial FROM trialsTable WHERE Date = ' num2str(selection(i,1)) ' AND Subject = "' subject '"']); %get list of file names entered into database
                else                     %this means we are grabbing everything for this date/block
                    trials=mksqlite(DataDB,['SELECT UniqueTrial FROM trialsTable WHERE Date = ' num2str(selection(i,1)) ' AND Block = ' num2str(selection(i,2)) ' AND Subject = "' subject '"']); %get list of file names entered into database
                end
                
                trialsList=[trialsList; trials.UniqueTrial];
            end
            
            trialsList=sort(trialsList);
        end
        
        function pairs = findIDAnglePairs(~,DataDB,trialsList,side)
            trialsString = sprintf('%.0f,' , trialsList);
            trialsString = trialsString(1:end-1);
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            if ~side
                props=mksqlite(DataDB,['SELECT SampleID, SampleRotation, Choice1ID, Choice1Rotation FROM trialsTable WHERE UniqueTrial IN (' trialsString ')']);
            else
                props=mksqlite(DataDB,['SELECT SampleID, SampleRotation, Choice2ID, Choice2Rotation FROM trialsTable WHERE UniqueTrial IN (' trialsString ')']);
            end
            if iscell(props) %if it returns a cell, it probably means that some of the trials don't have a haptic stimulus on the user-chosen side
                goodRows=~cellfun(@isempty,props(:,3)); %rows that have full data
                props=cell2mat(props(goodRows,:));
            end
            
            pairs=unique(props,'rows'); %nx4 array, n=number of unique combinations of vis id/angle and haptic id/angle
        end
        
        function pairs = fixDuplicates(~,pairs)
            %modified function that just finds first ID of equivalent
            global ObjectsDB objects
            
            %grab all the objects and figure out which ones are unique
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            objects=mksqlite(ObjectsDB,'SELECT blobID,blobName,unitsPerMM, pointsPerRad, nContacts, slotDepth, slotWidth, maxWidth, symmetric, pair, leftPair FROM objectsTable');
            [~, ~, uniqueIDs]=unique(cell2table(objects(:,2:end)),'rows','first'); %a is the unique entries, b is the row from original mat that each a comes from, c is sorta a unique identifier for each row of original mat
            objects=[objects array2table(uniqueIDs)];
            objects=cell2mat(table2cell(objects(:,[1 end])));
            
            %replace blobid with first blobid of exact same shape
            for i=1:size(pairs,1) %for each unique pairing of stim
                %grab blobID, find its shapeid, find first of that shapeid, grab its blobid
                pairs(i,1)=objects(find(objects(:,2)==objects(objects(:,1)==pairs(i,1),2),1),1);
            end
            
            pairs=unique(pairs);
        end


        function choices = findEquivalents(~,ID)
            %takes an object id and returns that and any identical id(s)
            global ObjectsDB
            
            %grab all the objects and figure out which ones are unique
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            objects=mksqlite(ObjectsDB,'SELECT blobID,blobName,unitsPerMM, pointsPerRad, nContacts, slotDepth, slotWidth, maxWidth, symmetric, pair, leftPair FROM objectsTable');
            [~, ~, uniqueIDs]=unique(cell2table(objects(:,2:end)),'rows','first'); %a is the unique entries, b is the row from original mat that each a comes from, c is sorta a unique identifier for each row of original mat
            objects=[objects array2table(uniqueIDs)];
            objects=cell2mat(table2cell(objects(:,[1 end])));
            if ischar(ID)
                ID=str2double(ID);
            end
            IDs=objects(objects(:,2)==objects(objects(:,1)==ID,2),1); %shape ids identical to chosen ID
            
            IDString = sprintf('%.0f,' , IDs);
            choices = IDString(1:end-1);
        end
        
        
        function vals = findUniqueValsInColumn(~,list,column)
            global DataDB
            trialsString = sprintf('%.0f,' , list);
            trialsString = trialsString(1:end-1);
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            vals=mksqlite(DataDB,['SELECT DISTINCT ' column ' FROM trialsTable WHERE UniqueTrial IN (' trialsString ')']);
            vals=sort(vals);
        end
        
        function [visID, visAngle, leftID, leftAngle, rightID, rightAngle] = getChoices(app)
            %get values
            visID = app.visID.Value;
            visAngle=app.visAngle.Value;
            leftID = app.leftID.Value;
            leftAngle=app.leftAngle.Value;
            rightID = app.rightID.Value;
            rightAngle=app.rightAngle.Value;
            
            %change the "Any" choices to the wildcard %, otherwise grab equivalent values
            if strcmp(visID,'Any')
                visID='SampleID';
            else
                visID = findEquivalents(app,visID);
            end
            if strcmp(visAngle,'Any')
                visAngle='SampleRotation';
            end
            if strcmp(leftID,'Any')
                leftID='Choice1ID';
            else
                leftID = findEquivalents(app,leftID);
            end
            if strcmp(leftAngle,'Any')
                leftAngle='Choice1Rotation';
            end
            if strcmp(rightID,'Any')
                rightID='Choice2ID';
            else
                rightID = findEquivalents(app,rightID);
            end
            if strcmp(rightAngle,'Any')
                rightAngle='Choice2Rotation';
            end
        end
        
        function selectionChanged(app)
            global DataDB
            global trialsList %full complement of trials for selected day/block
            
            [visID, visAngle, leftID, leftAngle, rightID, rightAngle] = getChoices(app);
            
            
            %create string of potential trials
            trialsString = sprintf('%.0f,' , trialsList);
            trialsString = trialsString(1:end-1);
            
            %grab trials which are selection from: subjects, day, block, vid, vangle, leftid, leftangle, rightid, right angle (usually under 10 ms)
            trials=mksqlite(DataDB, ['SELECT UniqueTrial FROM trialsTable WHERE UniqueTrial IN (' trialsString ') AND SampleID IN (' visID ') AND SampleRotation IN (' visAngle ') AND Choice1ID IN (' leftID ') AND Choice1Rotation IN (' leftAngle ')  AND Choice2ID IN (' rightID ') AND Choice2Rotation IN (' rightAngle ')']);
            
            %create string of potential trials
            trialsString = sprintf('%.0f,' , trials);
            trialsString = trialsString(1:end-1);
            
            %get info on these trials
            r=mksqlite(DataDB, ['SELECT Date, Block, Trial, Choice1ID, Choice1Rotation, SampleID, SampleRotation, Choice2ID, Choice2Rotation, Answer, Response FROM trialsTable WHERE UniqueTrial IN (' trialsString ')']);
            r=sortrows(r,[1 2 3]); %Make sure its sorted according to date, then block, then trial
            
            %do basic performance stats on these trials
            correct=r(:,10)==r(:,11);
            correct=(correct-0.5)*2;
            totalCorrect=sum(correct==1);
            totalNum=size(correct,1);
            
            %break out the
            r2=sortrows(r(:,4:end),[1 2 3 4 5 6]);  %throw out the day/block/trial and sort the rest
            types=unique(r2(:,1:6),'rows');         %grab only unique combos
            
            %step through combos, get performance for that condition, add to matrix
            for i=1:size(types,1)
                rows=find(ismember(r2(:,1:6),types(i,1:6),'rows'));   %rows where this condition is presented
                ar=r2(rows,7:8);                                 %answer and response
                ar=ar(:,1)==ar(:,2);                                %where they're equal
                ncorrect=sum(ar);
                ntotal=length(ar);
                percent=round(ncorrect/ntotal*100);
                types(i,7:9)=[ncorrect ntotal percent];
            end
            
            %change ids to first id
            for i=1:size(types,1)
                types(i,1) = fixDuplicates(app,types(i,1));
                types(i,3) = fixDuplicates(app,types(i,3));
                types(i,5) = fixDuplicates(app,types(i,5));
            end
            
            %convert to cell so we can remove individual cells
            types=num2cell(types);
            
            %find cells that are identical to the cell above and remove them
            for i=1:size(types,2)-3
                for ii=size(types,1):-1:2
                    if types{ii,i}==types{ii-1,i}
                        types(ii,i)={[]};
                    end
                end
            end
            
            %update table
            app.UITable.Data=types;
            
            %plot performance over time
            if totalNum < 2
                plot(app.PerfOverTime,1,1,'.')
                title(app.PerfOverTime,'Empty list')
            else
                hold(app.PerfOverTime,'off')
                plot(app.PerfOverTime,cumsum(correct))                
                hold(app.PerfOverTime,'on')
                plot(app.PerfOverTime,[1 length(correct)],[0 sum(correct)])
                title(app.PerfOverTime,[num2str(totalCorrect) ' out of ' num2str(totalNum) ' = ' num2str(round(totalCorrect/totalNum*100)) '%'])
            end


        end
        
        function drawShape(app, id, angle, ax)
            global ObjectsDB
            %haven't chosen a shape, nothing to plot
            if strcmp(id,'Any')
                plot(ax,0,0,'.')
                return;
            end
            %havent chosen an angle, pick 0 as default
            if strcmp(angle,'Any')
                angle=0;
            end
            
            %get shape
            shape=mksqlite(ObjectsDB, ['SELECT x, y FROM shapeTable' id]);
            if angle ~= 0
                [xs, ys]=rotatePoints(app,shape(:,1),shape(:,2),-deg2rad(str2double(angle)),0,0); %Rotated without slots
            else
                xs=shape(:,1);
                ys=shape(:,2);
            end
            set(ax,'Visible','off')
            axis(ax,'equal')
            plot(ax,xs,ys)
            
        end
        
        function [x_rotated, y_rotated] = rotatePoints(~,x,y,theta,x_center,y_center)
            v = [x';y'];
            center = repmat([x_center; y_center], 1, length(x)); %Matrix for centering
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)]; % define a counter-clockwise rotation matrix
            s = v - center;     % shift points in the plane so that the center of rotation is at the origin
            so = R*s;           % apply the rotation about the origin
            vo = so + center;   % shift again so the origin goes back to the desired center of rotation
            
            x_rotated = vo(1,:)';
            y_rotated = vo(2,:)';
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global ObjectsDB
            
            evalc('ObjectsDB = mksqlite(0,''open'', ''L:\stimuli\grasp\objects.db'')'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
            
            setSubjectValues(app,1); % reads what the species choice is, connects to DB, finds available subjects, and populates subject dropdown
            
            plot(app.leftGraph,0,0,'.')
            set(app.leftGraph,'Visible','off')
            plot(app.visGraph,0,0,'.')
            set(app.visGraph,'Visible','off')
            plot(app.rightGraph,0,0,'.')
            set(app.rightGraph,'Visible','off')
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            setSubjectValues(app,1); % reads what the species choice is, connects to DB, finds available subjects, and populates subject dropdown
        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
            setAvailableDaysAndBlocks(app,1)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            global trialsList DataDB pairs
            selectedNodes = app.Tree.SelectedNodes;         % high level list of selections
            subject = app.SubjectDropDown.Value;
            
            trialsList = findTrialIDs(app,selectedNodes,DataDB,subject);  % UniqueTrial ID for all selected trials ( ~12 ms )
            
            
            % grab values from db (~45 ms)
            sampleIDs = findUniqueValsInColumn(app,trialsList,'SampleID');
            sampleIDs = fixDuplicates(app,sampleIDs);
            sampleAngles =  findUniqueValsInColumn(app,trialsList,'SampleRotation');
            
            leftIDs = findUniqueValsInColumn(app,trialsList,'Choice1ID');
            leftIDs = fixDuplicates(app,leftIDs);
            leftAngles =  findUniqueValsInColumn(app,trialsList,'Choice1Rotation');
            
            rightIDs = findUniqueValsInColumn(app,trialsList,'Choice2ID');
            rightIDs = fixDuplicates(app,rightIDs);
            rightAngles =  findUniqueValsInColumn(app,trialsList,'Choice2Rotation');
            
            
            % update entry boxes
            app.visID.Items =       ['Any'; strtrim(cellstr(num2str(sampleIDs)))];
            app.visAngle.Items =    ['Any'; strtrim(cellstr(num2str(sampleAngles)))];
            app.leftID.Items =      ['Any'; strtrim(cellstr(num2str(leftIDs)))];
            app.leftAngle.Items =   ['Any'; strtrim(cellstr(num2str(leftAngles)))];
            app.rightID.Items =     ['Any'; strtrim(cellstr(num2str(rightIDs)))];
            app.rightAngle.Items =  ['Any'; strtrim(cellstr(num2str(rightAngles)))];
            
            
            %
            %             pairs = findIDAnglePairs(app,DataDB,trialsList,side);         %visual and haptic id/angle pairs
            %             pairs = fixDuplicates(app,pairs);                               %replace blob id with first equivalent blobid in database
            %             updateDropDowns(app,pairs,0,pairs(1,1),pairs(1,3));
        end

        % Callback function
        function visIDValueChanged(app, event)
            
        end

        % Close request function: 
        % ShowcorrectinselectedconditionsUIFigure
        function ShowcorrectinselectedconditionsUIFigureCloseRequest(app, event)
            %             global DataDB ObjectsDB
            
            delete(app)
            clear all
            
            
        end

        % Value changed function: leftID
        function leftIDValueChanged(app, event)
            drawShape(app, app.leftID.Value, app.leftAngle.Value, app.leftGraph)
            selectionChanged(app);
        end

        % Value changed function: rightID
        function rightIDValueChanged(app, event)
            drawShape(app, app.rightID.Value, app.rightAngle.Value, app.rightGraph)
            selectionChanged(app);
        end

        % Value changed function: leftAngle
        function leftAngleValueChanged(app, event)
            drawShape(app, app.leftID.Value, app.leftAngle.Value, app.leftGraph)
            selectionChanged(app);
        end

        % Value changed function: rightAngle
        function rightAngleValueChanged(app, event)
            drawShape(app, app.rightID.Value, app.rightAngle.Value, app.rightGraph)
            selectionChanged(app);
        end

        % Value changed function: visAngle
        function visAngleValueChanged(app, event)
            drawShape(app, app.visID.Value, app.visAngle.Value, app.visGraph)
            selectionChanged(app);
        end

        % Value changed function: visID
        function visIDValueChanged2(app, event)
            drawShape(app, app.visID.Value, app.visAngle.Value, app.visGraph)
            selectionChanged(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShowcorrectinselectedconditionsUIFigure = uifigure;
            app.ShowcorrectinselectedconditionsUIFigure.Name = 'Show % correct in selected conditions';
            app.ShowcorrectinselectedconditionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShowcorrectinselectedconditionsUIFigureCloseRequest, true);
            app.Analysis1Panel = uipanel(app.ShowcorrectinselectedconditionsUIFigure);
            app.Analysis1Panel.Title = 'Analysis 1';
            app.Tree = uitree(app.Analysis1Panel);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SpeciesDropDownLabel = uilabel(app.Analysis1Panel);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.Analysis1Panel);
            app.SpeciesDropDown.Items = {'Monkey', 'Human'};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SpeciesDropDown.Value = 'Monkey';
            app.SubjectDropDownLabel = uilabel(app.Analysis1Panel);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.Analysis1Panel);
            app.SubjectDropDown.Items = {'Option 1', 'Option 2'};
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.VisIDLabel = uilabel(app.Analysis1Panel);
            app.VisIDLabel.Text = 'Vis ID';
            app.visID = uidropdown(app.Analysis1Panel);
            app.visID.ValueChangedFcn = createCallbackFcn(app, @visIDValueChanged2, true);
            app.LeftIDLabel = uilabel(app.Analysis1Panel);
            app.LeftIDLabel.Text = 'Left ID';
            app.leftID = uidropdown(app.Analysis1Panel);
            app.leftID.ValueChangedFcn = createCallbackFcn(app, @leftIDValueChanged, true);
            app.RightIDLabel = uilabel(app.Analysis1Panel);
            app.RightIDLabel.Text = 'Right ID';
            app.rightID = uidropdown(app.Analysis1Panel);
            app.rightID.ValueChangedFcn = createCallbackFcn(app, @rightIDValueChanged, true);
            app.AngleLabel = uilabel(app.Analysis1Panel);
            app.AngleLabel.Text = 'Angle';
            app.leftAngle = uidropdown(app.Analysis1Panel);
            app.leftAngle.ValueChangedFcn = createCallbackFcn(app, @leftAngleValueChanged, true);
            app.AngleLabel_2 = uilabel(app.Analysis1Panel);
            app.AngleLabel_2.Text = 'Angle';
            app.rightAngle = uidropdown(app.Analysis1Panel);
            app.rightAngle.ValueChangedFcn = createCallbackFcn(app, @rightAngleValueChanged, true);
            app.AngleLabel_3 = uilabel(app.Analysis1Panel);
            app.AngleLabel_3.Text = 'Angle';
            app.visAngle = uidropdown(app.Analysis1Panel);
            app.visAngle.ValueChangedFcn = createCallbackFcn(app, @visAngleValueChanged, true);
            app.leftGraph = uiaxes(app.Analysis1Panel);
            title(app.leftGraph, 'Title')
            xlabel(app.leftGraph, 'X')
            ylabel(app.leftGraph, 'Y')
            app.visGraph = uiaxes(app.Analysis1Panel);
            title(app.visGraph, 'Title')
            xlabel(app.visGraph, 'X')
            ylabel(app.visGraph, 'Y')
            app.rightGraph = uiaxes(app.Analysis1Panel);
            title(app.rightGraph, 'Title')
            xlabel(app.rightGraph, 'X')
            ylabel(app.rightGraph, 'Y')
            app.PerfOverTime = uiaxes(app.Analysis1Panel);
            title(app.PerfOverTime, 'Title')
            xlabel(app.PerfOverTime, 'X')
            ylabel(app.PerfOverTime, 'Y')
            app.UITable = uitable(app.Analysis1Panel);
            app.UITable.ColumnName = {'Left ID'; 'Left Angle'; 'Vis ID'; 'Vis Angle'; 'Right ID'; 'Right Angle'; 'n Correct'; 'n Total'; '%'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = graspPerformanceViewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShowcorrectinselectedconditionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShowcorrectinselectedconditionsUIFigure)
        end
    end
end