classdef GraspPopulationDataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalyzewheretheylookandtouchmostUIFigure  matlab.ui.Figure
        Analysis1Panel          matlab.ui.container.Panel
        VisPanel                matlab.ui.container.Panel
        AxesV1                  matlab.ui.control.UIAxes
        IDDropDownLabel_2       matlab.ui.control.Label
        V1ID                    matlab.ui.control.DropDown
        AngleDropDownLabel_2    matlab.ui.control.Label
        V1Angle                 matlab.ui.control.DropDown
        HapticPanel             matlab.ui.container.Panel
        AxesH1                  matlab.ui.control.UIAxes
        ButtonGroup             matlab.ui.container.ButtonGroup
        LeftButton              matlab.ui.control.RadioButton
        RightButton             matlab.ui.control.RadioButton
        IDDropDownLabel_3       matlab.ui.control.Label
        H1ID                    matlab.ui.control.DropDown
        AngleDropDownLabel_3    matlab.ui.control.Label
        H1Angle                 matlab.ui.control.DropDown
        LinkID1                 matlab.ui.control.StateButton
        LinkAngle1              matlab.ui.control.StateButton
        StartEditFieldLabel     matlab.ui.control.Label
        StartEditField          matlab.ui.control.NumericEditField
        StopEditFieldLabel      matlab.ui.control.Label
        StopEditField           matlab.ui.control.NumericEditField
        Tree                    matlab.ui.container.Tree
        SpeciesDropDownLabel    matlab.ui.control.Label
        SpeciesDropDown         matlab.ui.control.DropDown
        SubjectDropDownLabel    matlab.ui.control.Label
        SubjectDropDown         matlab.ui.control.DropDown
        PlotButton              matlab.ui.control.Button
        Analysis2Panel          matlab.ui.container.Panel
        VisPanel_2              matlab.ui.container.Panel
        AxesV2                  matlab.ui.control.UIAxes
        IDDropDownLabel_4       matlab.ui.control.Label
        V2ID                    matlab.ui.control.DropDown
        AngleDropDownLabel_4    matlab.ui.control.Label
        V2Angle                 matlab.ui.control.DropDown
        HapticPanel_2           matlab.ui.container.Panel
        AxesH2                  matlab.ui.control.UIAxes
        ButtonGroup_2           matlab.ui.container.ButtonGroup
        LeftButton_2            matlab.ui.control.RadioButton
        RightButton_2           matlab.ui.control.RadioButton
        IDDropDownLabel_5       matlab.ui.control.Label
        H2ID                    matlab.ui.control.DropDown
        AngleDropDownLabel_5    matlab.ui.control.Label
        H2Angle                 matlab.ui.control.DropDown
        LinkID2                 matlab.ui.control.StateButton
        LinkAngle2              matlab.ui.control.StateButton
        StartEditField_2Label   matlab.ui.control.Label
        StartEditField_2        matlab.ui.control.NumericEditField
        StopEditField_2Label    matlab.ui.control.Label
        StopEditField_2         matlab.ui.control.NumericEditField
        Tree_2                  matlab.ui.container.Tree
        SpeciesDropDown_2Label  matlab.ui.control.Label
        SpeciesDropDown_2       matlab.ui.control.DropDown
        SubjectDropDown_2Label  matlab.ui.control.Label
        SubjectDropDown_2       matlab.ui.control.DropDown
        PlotButton_2            matlab.ui.control.Button
        TimeSliderLabel         matlab.ui.control.Label
        TimeSlider              matlab.ui.control.Slider
    end

    
    methods (Access = private)
        
        function setSubjectValues(app,side)
            %initialize variables given the chosen subject and analysis
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            if side==1
                global DataDB1
                % connect to db
                species = app.SpeciesDropDown.Value;
                db=['C:\Users\Ryan\Documents\Data\grasp' species 'Main.db'];
                evalc('DataDB1 = mksqlite(0,''open'', db)'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
                
                % attach grasp and eyes dbs
                mksqlite(DataDB1,['ATTACH "C:\Users\Ryan\Documents\Data\grasp' species 'Grasp.db" as "Grasp"'])
                mksqlite(DataDB1,['ATTACH "C:\Users\Ryan\Documents\Data\grasp' species 'Eyes.db" as "Eyes"'])
                
                %check what subjects are in db and set dropdown
                app.SubjectDropDown.Items=mksqlite(DataDB1,'SELECT DISTINCT Subject FROM trialsTable'); %get list of file names entered into database
            else
                global DataDB2
                % connect to db
                species = app.SpeciesDropDown_2.Value;
                db=['C:\Users\Ryan\Documents\Data\grasp' species 'Main.db'];
                evalc('DataDB2 = mksqlite(0,''open'', db)'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
                
                % attach grasp and eyes dbs
                mksqlite(DataDB2,['ATTACH "C:\Users\Ryan\Documents\Data\grasp' species 'Grasp.db" as "Grasp"'])
                mksqlite(DataDB2,['ATTACH "C:\Users\Ryan\Documents\Data\grasp' species 'Eyes.db" as "Eyes"'])                
                
                %check what subjects are in db and set dropdown
                app.SubjectDropDown_2.Items=mksqlite(DataDB2,'SELECT DISTINCT Subject FROM trialsTable'); %get list of file names entered into database
            end
            setAvailableDaysAndBlocks(app,side);
        end
        
        function setAvailableDaysAndBlocks(app,side)
            global DataDB1 DataDB2 tree1handles tree2handles
            
            if side == 1
                delete(tree1handles)
                dataDB='DataDB1';
                subject = app.SubjectDropDown.Value;
                tree='app.Tree';
            else
                delete(tree2handles)
                dataDB='DataDB2';
                subject = app.SubjectDropDown_2.Value;
                tree='app.Tree_2';
            end
            
            mksqlite( 'result_type', 1 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            dates=mksqlite(eval(dataDB),['SELECT Date, Block FROM trialsTable WHERE Subject = "' subject '"']);   %get list of file names entered into database
            
            dateBlocks=unique([dates.Date dates.Block],'rows');             %find unique combinations of day and block number
            dates=unique(dateBlocks(:,1));                                  %unique dates
            
            %enter dates/blocks into tree structure
            for i=1:size(dates,1)
                date = simpleToPrettyDate(app,dates(i));
                a(i)=uitreenode(eval(tree),'Text',date);
                blocks=dateBlocks(dateBlocks(:,1)==dates(i),2);
                for ii=1:size(blocks,1)
                    uitreenode(a(i),'Text',num2str(blocks(ii)),'NodeData',date);
                end
            end
            
            %need to keep track of tree handles so they can be deleted when subject changes
            if side==1
                tree1handles=a;
            else
                tree2handles=a;
            end
        end


        function date = simpleToPrettyDate(~,date)
            date=num2str(date);
            a=datenum(str2double(date(end-3:end)),str2double(date(1:end-6)),str2double(date(end-5:end-4)));
            date=datestr(a,29);
        end
        
        function date = prettyToSimpleDate(~,date)
            %changes from '1984-12-05' to 12051984
            date=str2double(datestr(datenum(date),'mmddyyyy'));
        end


        function trialsList = findTrialIDs(app,selectedNodes,DataDB,subject)
            selection=[];
            for i=1:size(selectedNodes,1)
                date=selectedNodes(i).NodeData; %usually date except when its only date than this is empty
                block=selectedNodes(i).Text;    %usually block except when its only date than this is date
                if isempty(date)                %convert date and block into 2 element array with date and block
                    row=[prettyToSimpleDate(app,block) nan];
                else
                    row=[prettyToSimpleDate(app,date) str2double(block)];
                end
                selection=[selection; row];     %add to list
            end
            
            [row,~]=find(isnan(selection));     %finds selections of dates
            
            if ~isempty(row)                    %looks for date selection and, if there are single blocks chosen on this date, nan's out the date
                for i=1:size(row,1)
                    if sum(selection(:,1)==selection(row(i),1)) > 1
                        selection(row(i),1)=nan;
                    end
                end
            end
            
            % selection column 1 is date, column 2 is block
            % where column 1 is nan, that means there are specific blocks chosen for that date
            % where only column 2 is nan, that means that we should grab everything for that date
            
            %find uniquetrials from database
            mksqlite( 'result_type', 1 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            trialsList=[];
            for i=1:size(selection,1)
                if isnan(selection(i,1)) %this means we are skipping this whole date and need to find individual blocks
                    continue;
                end
                
                if isnan(selection(i,2)) %this means we are grabbing everything for this date
                    trials=mksqlite(DataDB,['SELECT UniqueTrial FROM trialsTable WHERE Date = ' num2str(selection(i,1)) ' AND Subject = "' subject '"']); %get list of file names entered into database
                else                     %this means we are grabbing everything for this date/block
                    trials=mksqlite(DataDB,['SELECT UniqueTrial FROM trialsTable WHERE Date = ' num2str(selection(i,1)) ' AND Block = ' num2str(selection(i,2)) ' AND Subject = "' subject '"']); %get list of file names entered into database
                end
                
                trialsList=[trialsList; trials.UniqueTrial];
            end
            
            trialsList=sort(trialsList);
        end
        
        function pairs = findIDAnglePairs(~,DataDB,trialsList,side)
            trialsString = sprintf('%.0f,' , trialsList);
            trialsString = trialsString(1:end-1);
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            if ~side
                props=mksqlite(DataDB,['SELECT SampleID, SampleRotation, Choice1ID, Choice1Rotation FROM trialsTable WHERE UniqueTrial IN (' trialsString ')']);
            else
                props=mksqlite(DataDB,['SELECT SampleID, SampleRotation, Choice2ID, Choice2Rotation FROM trialsTable WHERE UniqueTrial IN (' trialsString ')']);
            end
            if iscell(props) %if it returns a cell, it probably means that some of the trials don't have a haptic stimulus on the user-chosen side
                goodRows=~cellfun(@isempty,props(:,3)); %rows that have full data
                props=cell2mat(props(goodRows,:));
            end
            
            pairs=unique(props,'rows'); %nx4 array, n=number of unique combinations of vis id/angle and haptic id/angle
        end
        
        function pairs = fixDuplicates(~,pairs)
            global ObjectsDB objects
            
            %grab all the objects and figure out which ones are unique
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            objects=mksqlite(ObjectsDB,'SELECT blobID,blobName,unitsPerMM, pointsPerRad, nContacts, slotDepth, slotWidth, maxWidth, symmetric, pair, leftPair FROM objectsTable');
            [~, ~, uniqueIDs]=unique(cell2table(objects(:,2:end)),'rows','first'); %a is the unique entries, b is the row from original mat that each a comes from, c is sorta a unique identifier for each row of original mat
            objects=[objects array2table(uniqueIDs)];
            objects=cell2mat(table2cell(objects(:,[1 end])));
            
            %replace blobid with first blobid of exact same shape
            for i=1:size(pairs,1) %for each unique pairing of stim
                %grab blobID, find its shapeid, find first of that shapeid, grab its blobid
                pairs(i,1)=objects(find(objects(:,2)==objects(objects(:,1)==pairs(i,1),2),1),1);
                pairs(i,3)=objects(find(objects(:,2)==objects(objects(:,1)==pairs(i,3),2),1),1);
            end
            
            pairs=unique(pairs,'rows');
        end
        
        function updateDropDowns(app,pairs,analysis,vidCurrent,hidCurrent)
            %set handles for dropdown boxes of interest
            if ~analysis    %analysis1
                vid=app.V1ID;
                vangle=app.V1Angle;
                hid=app.H1ID;
                hangle=app.H1Angle;
            else            %analysis2
                vid=app.V2ID;
                vangle=app.V2Angle;
                hid=app.H2ID;
                hangle=app.H2Angle;
            end
            
            %Figure out what angles are available for the chosen IDs
            vangleCurrent=unique(pairs(pairs(:,1)==vidCurrent,2));
            hangleCurrent=unique(pairs(pairs(:,1)==hidCurrent,4));
            
            %Set dropdowns
            set(vid,'Items',strtrim(cellstr(num2str(unique(pairs(:,1))))));
            set(vid,'Value',num2str(vidCurrent));
            set(vangle,'Items',cellstr(num2str(vangleCurrent)));
            
            set(hid,'Items',strtrim(cellstr(num2str(unique(pairs(:,3))))));
            set(hid,'Value',num2str(hidCurrent));
            set(hangle,'Items',cellstr(num2str(hangleCurrent)));
        end
        
        function linkDropDowns(~,v,h)
            vval=get(v,'Value');
            hitems=get(h,'Items');
            
            if ismember(vval,hitems) %if there exists an hid equal to current setting
                set(h,'Value',vval)
                set(h,'Enable',0)
            else
                warndlg('That isnt an option for haptic','what are you doing')
            end
        end
        
        function choices = findEquivalents(~,ID)
            global ObjectsDB
            
            %grab all the objects and figure out which ones are unique
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            objects=mksqlite(ObjectsDB,'SELECT blobID,blobName,unitsPerMM, pointsPerRad, nContacts, slotDepth, slotWidth, maxWidth, symmetric, pair, leftPair FROM objectsTable');
            [~, ~, uniqueIDs]=unique(cell2table(objects(:,2:end)),'rows','first'); %a is the unique entries, b is the row from original mat that each a comes from, c is sorta a unique identifier for each row of original mat
            objects=[objects array2table(uniqueIDs)];
            objects=cell2mat(table2cell(objects(:,[1 end])));
            
            IDs=objects(objects(:,2)==objects(objects(:,1)==ID,2),1); %shape ids identical to chosen ID
            
            IDString = sprintf('%.0f,' , IDs);
            choices = IDString(1:end-1);
        end
        
        function eyePos = getEyePos(~,trials,DataDB,startDelay,stopDelay)
            %empty matrix that will hold eye positions
            eyePos=nan(10000,2);
            c=0;
            
            mksqlite( 'result_type', 2 );  % 0: array of structs, 1:struct of arrays, 2: (cell) matrix
            mksqlite('param_wrapping', 1); %connection id is superfluous
            mksqlite(DataDB,'begin');
            for i=1:size(trials,1)
                %read sampling rate and values from table
                rate=mksqlite(DataDB,['SELECT Rate FROM Eyes.eyeTable' num2str(trials(i,1)) ' WHERE Rate IS NOT NULL']);
                positions=mksqlite(DataDB,['SELECT X, Y, Saccade, Blink FROM Eyes.eyeTable' num2str(trials(i,1)) ' WHERE X IS NOT NULL']);
                
                %determine sample number where we will start and stop for this trial
                start=ceil(rate*(trials(i,2)+startDelay)/1000);
                if stopDelay == 0  %special case, just use trial end
                    stop=floor(rate*trials(i,3)/1000);
                else
                    stop=floor(rate*(trials(i,2)+stopDelay)/1000);
                end
                stop = min([stop size(positions,1)]); %if user-chosen end point is later than trial end, set to trial end
                
                %create binary array for when we are in time period of interest
                inSample=zeros(size(positions,1),1);
                inSample(start:stop)=1;
                
                %grab eye positions that are not saccades or blinks and in time period
                rows=positions(:,3)==0 & positions(:,4)==0 & inSample==1;
                goodVals=positions(positions(:,3)==0 & positions(:,4)==0 & inSample==1,1:2);
                
                %add to array and increment counter
                eyePos(c+1:c+size(goodVals,1),1:2)=goodVals;
                c=c+size(goodVals,1);
            end
            mksqlite(DataDB,'commit');
            
            %remove extra nans
            eyePos(c+1:end,:)=[];
        end
        
        function [x_rotated, y_rotated] = rotatePoints(~,x,y,theta,x_center,y_center)
            v = [x';y'];
            center = repmat([x_center; y_center], 1, length(x)); %Matrix for centering
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)]; % define a counter-clockwise rotation matrix
            s = v - center;     % shift points in the plane so that the center of rotation is at the origin
            so = R*s;           % apply the rotation about the origin
            vo = so + center;   % shift again so the origin goes back to the desired center of rotation
            
            x_rotated = vo(1,:)';
            y_rotated = vo(2,:)';
        end
        
        function touchPos = getTouchPos(~,trials,DataDB,side,startDelay,stopDelay)
            %             global touchPos
            c=0;
            touchPos=nan(10000,12);
            mksqlite('param_wrapping', 1); %connection id is superfluous
            mksqlite(DataDB,'begin');
            
            for i=1:size(trials,1)
                %determine start and stop bounds
                start=num2str(trials(i,2)+startDelay);
                if stopDelay==0 %special case, go to end
                    stop=num2str(10000000);
                else
                    stop=num2str(trials(i,2)+stopDelay);
                end
                
                %read sampling values from table
                if side==0
                    pads=mksqlite(DataDB,['SELECT pctPad1_1,pctPad1_2,pctPad1_3,pctPad1_4,pctPad1_5,pctPad1_6,pctPad1_7,pctPad1_8,pctPad1_9,pctPad1_10,pctPad1_11,pctPad1_12 FROM Grasp.graspTable' num2str(trials(i,1)) ' WHERE TrialPeriod = 1 AND Time_1 > ' start ' AND Time_1 < ' stop]);
                else
                    pads=mksqlite(DataDB,['SELECT pctPad2_1,pctPad2_2,pctPad2_3,pctPad2_4,pctPad2_5,pctPad2_6,pctPad2_7,pctPad2_8,pctPad2_9,pctPad2_10,pctPad2_11,pctPad2_12 FROM Grasp.graspTable' num2str(trials(i,1)) ' WHERE TrialPeriod = 1 AND Time_2 > ' start ' AND Time_2 < ' stop]);
                end
                
                touchPos(c+1:c+size(pads,1),1:12)=pads;     %add to array
                c=c+size(pads,1);                           %increment counter
            end
            mksqlite(DataDB,'commit');

            touchPos(c+1:end,:)=[];     %remove extra nans
        end
        
        function t = calibrateTouchPos(~,t)
            t = t - repmat(min(t),size(t,1),1);     %sets each pad so that the min is zero
            t(t<10)=0;   %thresholding so minor changes are ignored
            t=mean(t,1);    %take average of each pad across entire list
            t=nthroot(t,1.5);      %compression into narrower range
            t=t-min(t);                %subtract min
            
            if max(t) > 1
                t=t/max(t);                %divide by max
            end
        end
        
        function [hm, minvals, maxvals] = makeHeatmap(~,eyePos,binWidth,scale)
            minvals = [-30 -30];    %min x and y position
            maxvals = [30 30];      %max x and y position
            rangevals = maxvals - minvals;
            grid=rangevals(1)/binWidth+1;
            xidx = 1 + round((eyePos(:,1) - minvals(1)) ./ rangevals(1) * (grid-1)); %figure out x position in grid for each point
            yidx = 1 + round((eyePos(:,2) - minvals(2)) ./ rangevals(2) * (grid-1)); %figure out y position in grid for each point
            hm = accumarray([yidx, xidx], 1, [grid,grid]);  %counts up the number of xs and ys
            hm=nthroot(hm,scale);
        end
        
        function results = findHighlightedPads(app,db)
            global ObjectsDB
            scales=mksqlite(db, ['SELECT SampleScale FROM trialsTable WHERE SampleID IN (' vchoices ') AND UniqueTrial IN (' trialsString ')']);
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global ObjectsDB
            
            evalc('ObjectsDB = mksqlite(0,''open'', ''L:\stimuli\grasp\objects.db'')'); %will create if it doesn't exist, evalc to suppress annoying version info being printed
            
            setSubjectValues(app,1); % reads what the species choice is, connects to DB, finds available subjects, and populates subject dropdown
            setSubjectValues(app,2); % reads what the species choice is, connects to DB, finds available subjects, and populates subject dropdown
            
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            setSubjectValues(app,1); % reads what the species choice is, connects to DB, finds available subjects, and populates subject dropdown
        end

        % Close request function: 
        % AnalyzewheretheylookandtouchmostUIFigure
        function AnalyzewheretheylookandtouchmostUIFigureCloseRequest(app, event)
            global DataDB1 DataDB2 ObjectsDB

            mksqlite(ObjectsDB,'close')
            mksqlite(DataDB1,'close')
            mksqlite(DataDB2,'close')
            
            delete(app)
        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
            setAvailableDaysAndBlocks(app,1)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            global trialsList1 DataDB1 pairs1
            selectedNodes = app.Tree.SelectedNodes;         % high level list of selections
            subject = app.SubjectDropDown.Value;
            side = app.RightButton.Value;
            trialsList1 = findTrialIDs(app,selectedNodes,DataDB1,subject);  % UniqueTrial ID for all selected trials
            pairs = findIDAnglePairs(app,DataDB1,trialsList1,side);         %visual and haptic id/angle pairs
            pairs1 = fixDuplicates(app,pairs);                               %replace blob id with first equivalent blobid in database
            updateDropDowns(app,pairs1,0,pairs1(1,1),pairs1(1,3));
        end

        % Value changed function: SpeciesDropDown_2
        function SpeciesDropDown_2ValueChanged(app, event)
            setSubjectValues(app,2);
        end

        % Value changed function: SubjectDropDown_2
        function SubjectDropDown_2ValueChanged(app, event)
            setAvailableDaysAndBlocks(app,2)
        end

        % Selection changed function: Tree_2
        function Tree_2SelectionChanged(app, event)
            global trialsList2 DataDB2 pairs2
            selectedNodes = app.Tree_2.SelectedNodes;
            subject = app.SubjectDropDown_2.Value;
            side = app.RightButton_2.Value;
            trialsList2 = findTrialIDs(app,selectedNodes,DataDB2,subject);  % UniqueTrial ID for all selected trials
            pairs = findIDAnglePairs(app,DataDB2,trialsList2,side); %visual and haptic id/angle pairs
            pairs2 = fixDuplicates(app,pairs);                               %replace blob id with first equivalent blobid in database
            updateDropDowns(app,pairs2,1,pairs2(1,1),pairs2(1,3));
        end

        % Value changed function: V1ID
        function V1IDValueChanged(app, event)
            global pairs1
            v1id = str2double(app.V1ID.Value);
            h1id = str2double(app.H1ID.Value);
            updateDropDowns(app,pairs1,0,v1id,h1id);
            
            %check if we're linking visual and haptic IDS and update if so
            if app.LinkID1.Value
                linkDropDowns(app,app.V1ID,app.H1ID)
            end
            
        end

        % Value changed function: H1ID
        function H1IDValueChanged(app, event)
            global pairs1
            v1id = str2double(app.V1ID.Value);
            h1id = str2double(app.H1ID.Value);
            updateDropDowns(app,pairs1,0,v1id,h1id);
        end

        % Value changed function: V2ID
        function V2IDValueChanged(app, event)
            global pairs2
            v2id = str2double(app.V2ID.Value);
            h2id = str2double(app.H2ID.Value);
            updateDropDowns(app,pairs2,1,v2id,h2id);
            
            %check if we're linking visual and haptic IDS and update if so
            if app.LinkID2.Value
                linkDropDowns(app,app.V2ID,app.H2ID)
            end
        end

        % Value changed function: H2ID
        function H2IDValueChanged(app, event)
            global pairs2
            v2id = str2double(app.V2ID.Value);
            h2id = str2double(app.H2ID.Value);
            updateDropDowns(app,pairs2,1,v2id,h2id);
        end

        % Value changed function: LinkID1
        function LinkID1ValueChanged(app, event)
            value = app.LinkID1.Value;
            
            if value %if we're supposed to align them
                linkDropDowns(app,app.V1ID,app.H1ID)
            else
                app.H1ID.Enable=1;
            end
        end

        % Value changed function: LinkAngle1
        function LinkAngle1ValueChanged(app, event)
            value = app.LinkAngle1.Value;
            if value %if we're supposed to align them
                linkDropDowns(app,app.V1Angle,app.H1Angle)
            else
                app.H1Angle.Enable=1;
            end
        end

        % Value changed function: LinkID2
        function LinkID2ValueChanged(app, event)
            value = app.LinkID2.Value;
            if value %if we're supposed to align them
                linkDropDowns(app,app.V2ID,app.H2ID)
            else
                app.H2ID.Enable=1;
            end
        end

        % Value changed function: LinkAngle2
        function LinkAngle2ValueChanged(app, event)
            value = app.LinkAngle2.Value;
            if value %if we're supposed to align them
                linkDropDowns(app,app.V2Angle,app.H2Angle)
            else
                app.H2Angle.Enable=1;
            end
        end

        % Value changed function: V1Angle
        function V1AngleValueChanged(app, event)
            %check if we're linking visual and haptic angles and update if so
            if app.LinkAngle1.Value
                linkDropDowns(app,app.V1Angle,app.H1Angle)
            end
        end

        % Value changed function: V2Angle
        function V2AngleValueChanged(app, event)
            %check if we're linking visual and haptic angles and update if so
            if app.LinkAngle2.Value
                linkDropDowns(app,app.V2Angle,app.H2Angle)
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global DataDB1 ObjectsDB trialsList1
            %find visual and haptic trials selected
            vID=app.V1ID.Value;
            vAngle=app.V1Angle.Value;
            hID=app.H1ID.Value;
            hAngle=app.H1Angle.Value;
            side = app.RightButton.Value;
            startDelay=app.StartEditField.Value;
            stopDelay=app.StopEditField.Value;
            
            %make sure something is selected
            if strcmp(vID, 'Option 1')
                return;
            end
            
            %create string of potential trials
            trialsString = sprintf('%.0f,' , trialsList1);
            trialsString = trialsString(1:end-1);
            
            %grab strings of all identical copies of the vis and haptic selected objects
            vchoices = findEquivalents(app, str2double(vID));
            hchoices = findEquivalents(app, str2double(hID));
            
            %grab trials which are selection from: subjects, day, block, vid, vangle, hid, hangle (8 ms)
            trials=mksqlite(DataDB1, ['SELECT UniqueTrial, StimOn, StimOff, Answer, Response FROM trialsTable WHERE SampleID IN (' vchoices ') AND sampleRotation = ' vAngle ' AND Choice' num2str(side+1) 'ID IN (' hchoices ') AND Choice' num2str(side+1) 'Rotation = ' hAngle ' AND UniqueTrial IN (' trialsString ')']);
            
            %calculate percent correct
            pc=num2str(round(sum(trials(:,4)==trials(:,5))/size(trials,1)*100));
            
            %get eye positions when we are in trial period, not blinking, not saccading (40 ms)
            eyePos=getEyePos(app,trials,DataDB1,startDelay,stopDelay);
            
            %get touch positions when we are in trial period (33 ms)
            touchPos=getTouchPos(app,trials,DataDB1,side,startDelay,stopDelay);  %gets appended list of all touches during touchperiod
            touchPos=calibrateTouchPos(app,touchPos);       %sets each pad so that the min is zero, then normalizes
            
            %get visual shape information
            scales=mksqlite(DataDB1, ['SELECT SampleScale FROM trialsTable WHERE SampleID IN (' vchoices ') AND UniqueTrial IN (' trialsString ')']);
            if length(unique(scales))~=1
                disp('not all same vis stim size')
                return
            end
            shape=mksqlite(ObjectsDB, ['SELECT x, y FROM shapeTable' vID]) * unique(scales);
            [vxs, vys]=rotatePoints(app,shape(:,1),shape(:,2),-deg2rad(str2double(vAngle)),0,0); %Rotated without slots
            
            
            %get haptic shape information
            hshape=mksqlite(ObjectsDB, ['SELECT x, y, pad FROM shapeTable' hID]);
            hshape(:,1:2)=hshape(:,1:2) * unique(scales);
            [hxs, hys]=rotatePoints(app,hshape(:,1),hshape(:,2),-deg2rad(str2double(hAngle)),0,0); %Rotated without slots
            
            
            %plot visual
            cla(app.AxesV1)
            app.AxesV1.Visible='off';
            hold(app.AxesV1,'on')
            colormap(app.AxesV1, 'gray')
            xlim(app.AxesV1,[-7 7])
            ylim(app.AxesV1,[-6.2 6.2])
            %make and plot heatmap in 0.25 x 0.25 deg bins
            [hm, minvals, maxvals] = makeHeatmap(app,eyePos,0.25,1); %3rd argument is bin size (x and y), 4th arg is root (raised to this power)
            imagesc(app.AxesV1, hm, 'xdata', [minvals(1), maxvals(1)], 'ydata', [minvals(2), maxvals(2)]); %plot the heatmap
            plot(app.AxesV1,vxs,vys,'w')    %plot the shape
            text(app.AxesV1,-6.8,-5.7,['n = ' num2str(size(trials,1)) ', ' pc '% correct'],'Color','w')
            %                         scatter(app.AxesV1,eyePos(:,1),eyePos(:,2),20,'filled','MarkerFaceAlpha',.05,'MarkerEdgeAlpha',.05)
            plot(app.AxesV1, 0, 0, '.g')
            
            %plot haptic
            cla(app.AxesH1)
            app.AxesH1.Visible='off';
            hold(app.AxesH1,'on')
            rectangle(app.AxesH1,'Position',[-20 -20 40 40],'FaceColor',[.5 .5 .5])
            fill(app.AxesH1,hxs,hys,'w','EdgeColor','w')
            xlim(app.AxesH1,[-7 7])
            ylim(app.AxesH1,[-6.2 6.2])
            for i=1:size(touchPos,2)
                plot(app.AxesH1,hxs(hshape(:,3)==i),hys(hshape(:,3)==i),'Color',[touchPos(i) touchPos(i) touchPos(i)],'LineWidth',5)
            end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            global DataDB2 trialsList2 ObjectsDB
            %get user selections
            vID=app.V2ID.Value;
            vAngle=app.V2Angle.Value;
            hID=app.H2ID.Value;
            hAngle=app.H2Angle.Value;
            side = app.RightButton_2.Value;
            startDelay=app.StartEditField_2.Value;
            stopDelay=app.StopEditField_2.Value;
            
            %make sure something is selected
            if strcmp(vID, 'Option 1')
                return;
            end
            
            %create string of potential trials
            trialsString = sprintf('%.0f,' , trialsList2);
            trialsString = trialsString(1:end-1);
            
            %grab strings of all identical copies of the vis and haptic selected objects
            vchoices = findEquivalents(app, str2double(vID));
            hchoices = findEquivalents(app, str2double(hID));
            
            %grab trials which are selection from: subjects, day, block, vid, vangle, hid, hangle
            trials=mksqlite(DataDB2, ['SELECT UniqueTrial, StimOn, StimOff, Answer, Response FROM trialsTable WHERE SampleID IN (' vchoices ') AND sampleRotation = ' vAngle ' AND Choice' num2str(side+1) 'ID IN (' hchoices ') AND Choice' num2str(side+1) 'Rotation = ' hAngle ' AND UniqueTrial IN (' trialsString ')']);
            
            %calculate percent correct
            pc=num2str(round(sum(trials(:,4)==trials(:,5))/size(trials,1)*100));
            
            %get eye positions when we are in trial period, not blinking, not saccading
            eyePos=getEyePos(app,trials,DataDB2,startDelay,stopDelay);
            
            %get touch positions when we are in trial period
            touchPos=getTouchPos(app,trials,DataDB2,side,startDelay,stopDelay);  %gets appended list of all touches during touchperiod
            touchPos=calibrateTouchPos(app,touchPos);       %sets each pad so that the min is zero, then normalizes
            
            %get visual shape information
            scales=mksqlite(DataDB2, ['SELECT SampleScale FROM trialsTable WHERE SampleID IN (' vchoices ') AND UniqueTrial IN (' trialsString ')']);
            if length(unique(scales))~=1
                disp('not all same vis stim size')
                return
            end
            shape=mksqlite(ObjectsDB, ['SELECT x, y FROM shapeTable' vID]) * unique(scales);
            [vxs, vys]=rotatePoints(app,shape(:,1),shape(:,2),-deg2rad(str2double(vAngle)),0,0); %Rotated without slots
            
            %get haptic shape information
            hshape=mksqlite(ObjectsDB, ['SELECT x, y, pad FROM shapeTable' hID]);
            hshape(:,1:2)=hshape(:,1:2) * unique(scales);
            [hxs, hys]=rotatePoints(app,hshape(:,1),hshape(:,2),-deg2rad(str2double(hAngle)),0,0); %Rotated without slots
            
            
            %plot visual
            cla(app.AxesV2)
            app.AxesV2.Visible='off';
            hold(app.AxesV2,'on')
            colormap(app.AxesV2, 'gray')
            xlim(app.AxesV2,[-7 7])
            ylim(app.AxesV2,[-6.2 6.2])
            %make and plot heatmap in 0.25 x 0.25 deg bins
            [hm, minvals, maxvals] = makeHeatmap(app,eyePos,0.25,1); %3rd argument is bin size (x and y), 4th arg is root (raised to this power)
            imagesc(app.AxesV2, hm, 'xdata', [minvals(1), maxvals(1)], 'ydata', [minvals(2), maxvals(2)]); %plot the heatmap
            plot(app.AxesV2,vxs,vys,'w')    %plot the shape
            text(app.AxesV2,-6.8,-5.7,['n = ' num2str(size(trials,1)) ', ' pc '% correct'],'Color','w')
            %                         scatter(app.AxesV2,eyePos(:,1),eyePos(:,2),20,'filled','MarkerFaceAlpha',.05,'MarkerEdgeAlpha',.05)
            plot(app.AxesV2, 0, 0, '.g')
            
            %plot haptic
            cla(app.AxesH2)
            app.AxesH2.Visible='off';
            hold(app.AxesH2,'on')
            rectangle(app.AxesH2,'Position',[-20 -20 40 40],'FaceColor',[.5 .5 .5])
            fill(app.AxesH2,hxs,hys,'w','EdgeColor','w')
            xlim(app.AxesH2,[-7 7])
            ylim(app.AxesH2,[-6.2 6.2])
            for i=1:size(touchPos,2)
                plot(app.AxesH2,hxs(hshape(:,3)==i),hys(hshape(:,3)==i),'Color',[touchPos(i) touchPos(i) touchPos(i)],'LineWidth',5)
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global trialsList1 DataDB1 pairs1
            selectedNodes = app.Tree.SelectedNodes;         % high level list of selections
            subject = app.SubjectDropDown.Value;
            side = app.RightButton.Value;
            trialsList1 = findTrialIDs(app,selectedNodes,DataDB1,subject);  % UniqueTrial ID for all selected trials
            pairs = findIDAnglePairs(app,DataDB1,trialsList1,side);         %visual and haptic id/angle pairs
            pairs1 = fixDuplicates(app,pairs);                               %replace blob id with first equivalent blobid in database
            updateDropDowns(app,pairs1,0,pairs1(1,1),pairs1(1,3));
            
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            global trialsList2 DataDB2 pairs2
            selectedNodes = app.Tree_2.SelectedNodes;         % high level list of selections
            subject = app.SubjectDropDown_2.Value;
            side = app.RightButton_2.Value;
            trialsList2 = findTrialIDs(app,selectedNodes,DataDB2,subject);  % UniqueTrial ID for all selected trials
            pairs = findIDAnglePairs(app,DataDB2,trialsList2,side);         %visual and haptic id/angle pairs
            pairs2 = fixDuplicates(app,pairs);                               %replace blob id with first equivalent blobid in database
            updateDropDowns(app,pairs2,1,pairs2(1,1),pairs2(1,3));
            
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            timeMin=round(changingValue-100);
            timeMax=round(changingValue+100);
            
            app.StartEditField.Value = timeMin;
            app.StopEditField.Value = timeMax;
            
            app.StartEditField_2.Value = timeMin;
            app.StopEditField_2.Value = timeMax;
            
            PlotButtonPushed(app,0)
            PlotButton_2Pushed(app,0)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalyzewheretheylookandtouchmostUIFigure = uifigure;
            app.AnalyzewheretheylookandtouchmostUIFigure.Name = 'Analyze where they look and touch most';
            app.AnalyzewheretheylookandtouchmostUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnalyzewheretheylookandtouchmostUIFigureCloseRequest, true);
            app.Analysis1Panel = uipanel(app.AnalyzewheretheylookandtouchmostUIFigure);
            app.Analysis1Panel.Title = 'Analysis 1';
            app.VisPanel = uipanel(app.Analysis1Panel);
            app.VisPanel.Title = 'Vis';
            app.AxesV1 = uiaxes(app.VisPanel);
            app.IDDropDownLabel_2 = uilabel(app.VisPanel);
            app.IDDropDownLabel_2.Text = 'ID';
            app.V1ID = uidropdown(app.VisPanel);
            app.V1ID.ValueChangedFcn = createCallbackFcn(app, @V1IDValueChanged, true);
            app.AngleDropDownLabel_2 = uilabel(app.VisPanel);
            app.AngleDropDownLabel_2.Text = 'Angle';
            app.V1Angle = uidropdown(app.VisPanel);
            app.V1Angle.ValueChangedFcn = createCallbackFcn(app, @V1AngleValueChanged, true);
            app.HapticPanel = uipanel(app.Analysis1Panel);
            app.HapticPanel.Title = 'Haptic';
            app.AxesH1 = uiaxes(app.HapticPanel);
            app.ButtonGroup = uibuttongroup(app.HapticPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.LeftButton = uiradiobutton(app.ButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.ButtonGroup);
            app.RightButton.Text = 'Right';
            app.IDDropDownLabel_3 = uilabel(app.HapticPanel);
            app.IDDropDownLabel_3.Text = 'ID';
            app.H1ID = uidropdown(app.HapticPanel);
            app.H1ID.ValueChangedFcn = createCallbackFcn(app, @H1IDValueChanged, true);
            app.AngleDropDownLabel_3 = uilabel(app.HapticPanel);
            app.AngleDropDownLabel_3.Text = 'Angle';
            app.H1Angle = uidropdown(app.HapticPanel);
            app.LinkID1 = uibutton(app.Analysis1Panel, 'state');
            app.LinkID1.ValueChangedFcn = createCallbackFcn(app, @LinkID1ValueChanged, true);
            app.LinkID1.Text = 'Link ID';
            app.LinkAngle1 = uibutton(app.Analysis1Panel, 'state');
            app.LinkAngle1.ValueChangedFcn = createCallbackFcn(app, @LinkAngle1ValueChanged, true);
            app.LinkAngle1.Text = 'Link Angle';
            app.StartEditFieldLabel = uilabel(app.Analysis1Panel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Analysis1Panel, 'numeric');
            app.StopEditFieldLabel = uilabel(app.Analysis1Panel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.Analysis1Panel, 'numeric');
            app.Tree = uitree(app.Analysis1Panel);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SpeciesDropDownLabel = uilabel(app.Analysis1Panel);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.Analysis1Panel);
            app.SpeciesDropDown.Items = {'Monkey', 'Human'};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SpeciesDropDown.Value = 'Monkey';
            app.SubjectDropDownLabel = uilabel(app.Analysis1Panel);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.Analysis1Panel);
            app.SubjectDropDown.Items = {'Option 1', 'Option 2'};
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.PlotButton = uibutton(app.Analysis1Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Analysis2Panel = uipanel(app.AnalyzewheretheylookandtouchmostUIFigure);
            app.Analysis2Panel.Title = 'Analysis 2';
            app.VisPanel_2 = uipanel(app.Analysis2Panel);
            app.VisPanel_2.Title = 'Vis';
            app.AxesV2 = uiaxes(app.VisPanel_2);
            app.IDDropDownLabel_4 = uilabel(app.VisPanel_2);
            app.IDDropDownLabel_4.Text = 'ID';
            app.V2ID = uidropdown(app.VisPanel_2);
            app.V2ID.ValueChangedFcn = createCallbackFcn(app, @V2IDValueChanged, true);
            app.AngleDropDownLabel_4 = uilabel(app.VisPanel_2);
            app.AngleDropDownLabel_4.Text = 'Angle';
            app.V2Angle = uidropdown(app.VisPanel_2);
            app.V2Angle.ValueChangedFcn = createCallbackFcn(app, @V2AngleValueChanged, true);
            app.HapticPanel_2 = uipanel(app.Analysis2Panel);
            app.HapticPanel_2.Title = 'Haptic';
            app.AxesH2 = uiaxes(app.HapticPanel_2);
            app.ButtonGroup_2 = uibuttongroup(app.HapticPanel_2);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.LeftButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.LeftButton_2.Text = 'Left';
            app.LeftButton_2.Value = true;
            app.RightButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.RightButton_2.Text = 'Right';
            app.IDDropDownLabel_5 = uilabel(app.HapticPanel_2);
            app.IDDropDownLabel_5.Text = 'ID';
            app.H2ID = uidropdown(app.HapticPanel_2);
            app.H2ID.ValueChangedFcn = createCallbackFcn(app, @H2IDValueChanged, true);
            app.AngleDropDownLabel_5 = uilabel(app.HapticPanel_2);
            app.AngleDropDownLabel_5.Text = 'Angle';
            app.H2Angle = uidropdown(app.HapticPanel_2);
            app.LinkID2 = uibutton(app.Analysis2Panel, 'state');
            app.LinkID2.ValueChangedFcn = createCallbackFcn(app, @LinkID2ValueChanged, true);
            app.LinkID2.Text = 'Link ID';
            app.LinkAngle2 = uibutton(app.Analysis2Panel, 'state');
            app.LinkAngle2.ValueChangedFcn = createCallbackFcn(app, @LinkAngle2ValueChanged, true);
            app.LinkAngle2.Text = 'Link Angle';
            app.StartEditField_2Label = uilabel(app.Analysis2Panel);
            app.StartEditField_2Label.Text = 'Start';
            app.StartEditField_2 = uieditfield(app.Analysis2Panel, 'numeric');
            app.StopEditField_2Label = uilabel(app.Analysis2Panel);
            app.StopEditField_2Label.Text = 'Stop';
            app.StopEditField_2 = uieditfield(app.Analysis2Panel, 'numeric');
            app.Tree_2 = uitree(app.Analysis2Panel);
            app.Tree_2.Multiselect = 'on';
            app.Tree_2.SelectionChangedFcn = createCallbackFcn(app, @Tree_2SelectionChanged, true);
            app.SpeciesDropDown_2Label = uilabel(app.Analysis2Panel);
            app.SpeciesDropDown_2Label.Text = 'Species';
            app.SpeciesDropDown_2 = uidropdown(app.Analysis2Panel);
            app.SpeciesDropDown_2.Items = {'Monkey', 'Human'};
            app.SpeciesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDown_2ValueChanged, true);
            app.SpeciesDropDown_2.Value = 'Monkey';
            app.SubjectDropDown_2Label = uilabel(app.Analysis2Panel);
            app.SubjectDropDown_2Label.Text = 'Subject';
            app.SubjectDropDown_2 = uidropdown(app.Analysis2Panel);
            app.SubjectDropDown_2.Items = {'Option 1', 'Option 2'};
            app.SubjectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDown_2ValueChanged, true);
            app.PlotButton_2 = uibutton(app.Analysis2Panel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.TimeSliderLabel = uilabel(app.AnalyzewheretheylookandtouchmostUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.AnalyzewheretheylookandtouchmostUIFigure);
            app.TimeSlider.Limits = [-500 2000];
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GraspPopulationDataViewer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalyzewheretheylookandtouchmostUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalyzewheretheylookandtouchmostUIFigure)
        end
    end
end